classdef SDRadarApp_MCG_RealTime_Spectro_Demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AncortekSDRadarUIFigure       matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        SDRKITInformationPanel        matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        RFSNEditFieldLabel            matlab.ui.control.Label
        RFSNEditField                 matlab.ui.control.EditField
        PMSNEditFieldLabel            matlab.ui.control.Label
        PMSNEditField                 matlab.ui.control.EditField
        ModelEditField                matlab.ui.control.EditField
        ModelDetectionButton          matlab.ui.control.Button
        BandwidthControlPanel         matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        RampstartGHzSlider            matlab.ui.control.Slider
        RampstopGHzSlider             matlab.ui.control.Slider
        RampstartGHzEditFieldLabel    matlab.ui.control.Label
        RampstartGHzEditField         matlab.ui.control.NumericEditField
        RampstopGHzEditFieldLabel     matlab.ui.control.Label
        RampstopGHzEditField          matlab.ui.control.NumericEditField
        ActiveChannelSelectionPanel   matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        Tx1CheckBox                   matlab.ui.control.CheckBox
        Tx2CheckBox                   matlab.ui.control.CheckBox
        Rx1CheckBox                   matlab.ui.control.CheckBox
        Rx2CheckBox                   matlab.ui.control.CheckBox
        Rx3CheckBox                   matlab.ui.control.CheckBox
        Rx4CheckBox                   matlab.ui.control.CheckBox
        RadarParameterControlPanel    matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        ModulationDropDownLabel       matlab.ui.control.Label
        ModulationDropDown            matlab.ui.control.DropDown
        SweeptimeusDropDownLabel      matlab.ui.control.Label
        SweeptimeusDropDown           matlab.ui.control.DropDown
        SamplesweepDropDownLabel      matlab.ui.control.Label
        SamplesPerSweepDropDown       matlab.ui.control.DropDown
        MessagePanel                  matlab.ui.container.Panel
        GridLayout8                   matlab.ui.container.GridLayout
        MessageEditField              matlab.ui.control.EditField
        MessageLamp                   matlab.ui.control.Lamp
        DataRecordingPanel            matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        RecordButton                  matlab.ui.control.Button
        SecondsSpinnerLabel           matlab.ui.control.Label
        RecordSpinner                 matlab.ui.control.Spinner
        StreamingPanel                matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        StreamingSwitch               matlab.ui.control.Switch
        StreamingOnLamp               matlab.ui.control.Lamp
        StreamingOffLamp              matlab.ui.control.Lamp
        CenterPanel                   matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        SpectrogramTab                matlab.ui.container.Tab
        GridLayout22                  matlab.ui.container.GridLayout
        UIAxes_spectrogramScope       matlab.ui.control.UIAxes
        MeanDopplerShiftTab           matlab.ui.container.Tab
        GridLayout23                  matlab.ui.container.GridLayout
        UIAxes_meanDopplerShiftScope  matlab.ui.control.UIAxes
        TimeScopeTab                  matlab.ui.container.Tab
        GridLayout11                  matlab.ui.container.GridLayout
        UIAxes_timeScope              matlab.ui.control.UIAxes
        TabGroup_2                    matlab.ui.container.TabGroup
        RangeScopeTab                 matlab.ui.container.Tab
        GridLayout14                  matlab.ui.container.GridLayout
        UIAxes_rangeScope             matlab.ui.control.UIAxes
        RangeDopplerTab               matlab.ui.container.Tab
        GridLayout15                  matlab.ui.container.GridLayout
        UIAxes_rangeDoppler           matlab.ui.control.UIAxes
        RangeWaterfallTab             matlab.ui.container.Tab
        GridLayout18                  matlab.ui.container.GridLayout
        UIAxes_rangeWaterfall         matlab.ui.control.UIAxes
        VelocityScopeTab              matlab.ui.container.Tab
        GridLayout19                  matlab.ui.container.GridLayout
        UIAxes_velocityScope          matlab.ui.control.UIAxes
        VelocityWaterfallTab          matlab.ui.container.Tab
        GridLayout20                  matlab.ui.container.GridLayout
        UIAxes_velocityWaterfall      matlab.ui.control.UIAxes
        ViewingTxDropDownLabel        matlab.ui.control.Label
        ViewingTxDropDown             matlab.ui.control.DropDown
        ViewingRxDropDown             matlab.ui.control.DropDown
        ViewingRxDropDownLabel        matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        GridLayout12                  matlab.ui.container.GridLayout
        FilteringPanel                matlab.ui.container.Panel
        GridLayout13                  matlab.ui.container.GridLayout
        RangeNotchFilterCheckBox      matlab.ui.control.CheckBox
        RangeNotchSlider              matlab.ui.control.Slider
        DopplerNotchSlider            matlab.ui.control.Slider
        DopplerNotchFilterCheckBox    matlab.ui.control.CheckBox
        DynamicRangePanel             matlab.ui.container.Panel
        GridLayout16                  matlab.ui.container.GridLayout
        MinKnobLabel                  matlab.ui.control.Label
        DRMinKnob                     matlab.ui.control.Knob
        MaxKnobLabel                  matlab.ui.control.Label
        DRMaxKnob                     matlab.ui.control.Knob
        DRAutoCheckBox                matlab.ui.control.CheckBox
        WaterfallParametersPanel      matlab.ui.container.Panel
        GridLayout17                  matlab.ui.container.GridLayout
        HistorysizeframeSpinnerLabel  matlab.ui.control.Label
        HistorysizeframeSpinner       matlab.ui.control.Spinner
        Filtering2Panel               matlab.ui.container.Panel
        GridLayout21                  matlab.ui.container.GridLayout
        HighPassFilterCheckBox        matlab.ui.control.CheckBox
        PCACheckBox                   matlab.ui.control.CheckBox
        PrincipalComponentsSpinnerLabel  matlab.ui.control.Label
        PrincipalComponentsSpinner    matlab.ui.control.Spinner
        SweepstoProcessSpinnerLabel   matlab.ui.control.Label
        SweepstoProcessSpinner        matlab.ui.control.Spinner
        SaveSISOStreamPanel           matlab.ui.container.Panel
        SaveRealtimestreamCheckBox    matlab.ui.control.CheckBox
        RecordSISOSIMOStreamPanel     matlab.ui.container.Panel
        SecondsSpinnerLabel_2         matlab.ui.control.Label
        SecondsSpinner                matlab.ui.control.Spinner
        StartButton                   matlab.ui.control.StateButton
        Label                         matlab.ui.control.Label
        RecordLamp                    matlab.ui.control.Lamp
        FileMenu                      matlab.ui.container.Menu
        SavecurrentconfigMenu         matlab.ui.container.Menu
        LoadapreviousconfigMenu       matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        USB_BUFFER_BLOCK_SIZE = 512;  % in bytes
        USB_BUFFER_LEFTOVER = 1024; % in bytes
        max_data_rate = 2048000; % uint_16 samples per second
        
        model_c % part model number in character array
        model_s % full model number in string
        
        nTx % number of active transmitter
        nTx_max % maximum number of transmitter
        nRx % number of active receiver
        nRx_max % maximum number of receiver
        sTime % sweep time in second
        nSamp % samples per sweep
        nSamp_max % maximum samples per sweep
        fStart % in Hz
        fStop % in Hz
        bw_max_GHz % maximum sweeping bandwidth in GHz
        
        modOpt = struct;
        stOpt = struct;
        sampOpt = struct;
        txOpt = struct;
        rxOpt = struct;
        
        % Info request command header
        getInfo_h = uint16(hex2dec('FA00'));
        
        % General setting command headers
        setMod_h = uint16(hex2dec('E100'));
        setSt_h = uint16(hex2dec('E200'));
        setSamp_h = uint16(hex2dec('E300'));
        setTx_h = uint16(hex2dec('E400'));
        setRx_h = uint16(hex2dec('E500'));
        
        % PLL setting command headers
        setSweepstop_Hi_h = uint16(hex2dec('D100'));
        setSweepstop_Lo_h = uint16(hex2dec('D200'));
        setReg03_Hi_h = uint16(hex2dec('C100'));
        setReg03_Mi_h = uint16(hex2dec('C200'));
        setReg03_Lo_h = uint16(hex2dec('C300'));
        setReg04_Hi_h = uint16(hex2dec('C400'));
        setReg04_Mi_h = uint16(hex2dec('C500'));
        setReg04_Lo_h = uint16(hex2dec('C600'));
        setReg0A_Hi_h = uint16(hex2dec('C700'));
        setReg0A_Mi_h = uint16(hex2dec('C800'));
        setReg0A_Lo_h = uint16(hex2dec('C900'));
        setReg0C_Hi_h = uint16(hex2dec('CA00'));
        setReg0C_Mi_h = uint16(hex2dec('CB00'));
        setReg0C_Lo_h = uint16(hex2dec('CC00'));
        setReg0D_Hi_h = uint16(hex2dec('CD00'));
        setReg0D_Mi_h = uint16(hex2dec('CE00'));
        setReg0D_Lo_h = uint16(hex2dec('CF00'));
        
        setSweepstop_Hi
        setSweepstop_Lo
        setReg03_Hi
        setReg03_Mi
        setReg03_Lo
        setReg04_Hi
        setReg04_Mi
        setReg04_Lo
        setReg0A_Hi
        setReg0A_Mi
        setReg0A_Lo
        setReg0C_Hi
        setReg0C_Mi
        setReg0C_Lo
        setReg0D_Hi
        setReg0D_Mi
        setReg0D_Lo
        
        cmd_len = 1024; % Command length in uint16
        cmd_rep = 1024 % Command repetition times
        cmd_getInfo % Command for getting device info
        cmd_setMod % Command for setting modulation scheme
        cmd_setSt % Command for setting sweep time
        cmd_setSamp % Command for setting "samples/sweep"
        cmd_setTx
        cmd_setRx
        % cmd_setPll % for combining all pll registers
        cmd_setSweepstop_Hi
        cmd_setSweepstop_Lo
        cmd_setReg03_Hi
        cmd_setReg03_Mi
        cmd_setReg03_Lo
        cmd_setReg04_Hi
        cmd_setReg04_Mi
        cmd_setReg04_Lo
        cmd_setReg0A_Hi
        cmd_setReg0A_Mi
        cmd_setReg0A_Lo
        cmd_setReg0C_Hi
        cmd_setReg0C_Mi
        cmd_setReg0C_Lo
        cmd_setReg0D_Hi
        cmd_setReg0D_Mi
        cmd_setReg0D_Lo
        
        % Plot related
        time_scope_rebuild = 1; % rebuild toggle
        range_scope_rebuild = 1;
        range_doppler_rebuild = 1;
        range_waterfall_rebuild = 1;
        velocity_scope_rebuild = 1;
        velocity_waterfall_rebuild = 1;
        Range_WF_flag = 1;
        Velocity_WF_flag = 1;
        hLine_time_scope
        hLine_range_scope
        hLineV_range_scope
        hText_range_scope
        hLine_velocity_scope
        hLineV_velocity_scope
        hText_velocity_scope
        hRngDopp
        hRange_WF
        hVelocity_WF
        spectrogram_scope_rebuild = 1 % spectrogram rebuild toggle
        mean_doppler_shift_rebuild = 1 % MDS rebuild toggle
        
        % Processing related
        PN = 0   ; % number of sweeps taken in for processing
        N_avg = 5; % averging factor across sweep
        backgroundDataCollection = 1;
        DCCancelling = 1;
        sampRate
        bw % fmcw bandwidth
        fc % center frequency
        rngMax % maximum unambiguous range
        prf % pulse repetition frequency
        velMax % maximum unambigous velocity
        Nfftr = 1024; % range fft point
        Nfftv = 1024; % velocity fft point
        dec = 16; % sampling rate decimation factor
        
        hLine_mean_doppler_shift_scope; % Description
        hSurf_spectrogram_scope; % Description
        numPCA_components % The number of prinicpal components
        saveStream % checkbox to save stream data
        stream_save_dir % Description
        complex_data_2 % Description
    end
    
    methods (Access = private)
        
        function PLL_register_setup(app)
            % Used for setting PLL regesters for FMCW bandwidth
            Tref  = 1/50e6;
            
            switch app.sTime
                case 125e-6
                    Max_Sweepover = 1024;
                    T_downchirp = 5e-6;
                    Tramp_percent = 1-T_downchirp*1.5/app.sTime;
                    Sweep_N = ceil(Max_Sweepover*(Tramp_percent+0.01));
                    Tramp = app.sTime*Tramp_percent;
                case 250e-6
                    Max_Sweepover = 2048;
                    T_downchirp = 5e-6;
                    Tramp_percent = 1-T_downchirp*1.5/app.sTime;
                    Sweep_N = ceil(Max_Sweepover*(Tramp_percent+0.01));
                    Tramp = app.sTime*Tramp_percent;
                case 0.5e-3
                    Max_Sweepover = 4096;
                    T_downchirp = 70e-6;
                    Tramp_percent = 1-T_downchirp*1.5/app.sTime;
                    Sweep_N = ceil(Max_Sweepover*(Tramp_percent+0.01));
                    Tramp = app.sTime*Tramp_percent;
                case 1e-3
                    Max_Sweepover = 8192;
                    T_downchirp = 90e-6;
                    Tramp_percent = 1-T_downchirp*1.5/app.sTime;
                    Sweep_N = ceil(Max_Sweepover*(Tramp_percent+0.01));
                    Tramp = app.sTime*Tramp_percent;
                case 2e-3
                    Max_Sweepover = 16384;
                    T_downchirp = 110e-6;
                    Tramp_percent = 1-T_downchirp*1.5/app.sTime;
                    Sweep_N = ceil(Max_Sweepover*(Tramp_percent+0.01));
                    Tramp = app.sTime*Tramp_percent;
                case 4e-3
                    Max_Sweepover = 32768;
                    T_downchirp = 90e-6;
                    Tramp_percent = 1-T_downchirp*1.5/app.sTime;
                    Sweep_N = ceil(Max_Sweepover*(Tramp_percent+0.01));
                    Tramp = app.sTime*Tramp_percent;
                case 8e-3
                    Max_Sweepover = 65536;
                    T_downchirp = 90e-6;
                    Tramp_percent = 1-T_downchirp*1.5/app.sTime;
                    Sweep_N = ceil(Max_Sweepover*(Tramp_percent+0.01));
                    Tramp = app.sTime*Tramp_percent;
            end
            
            if app.fStart > 20e9
                % Frequency at prescalar: f_vco = f_ps/16
                F_start = app.fStart  / 16;
                F_stop = app.fStop  / 16;
            elseif (app.fStart <= 20e9 && app.fStart >= 3e9)
                % Frequency at prescalar: f_vco = f_ps/2
                F_start = app.fStart / 2;
                F_stop = app.fStop / 2;
            else
                F_start = app.fStart;
                F_stop = app.fStop;
            end
            
            % Calculate togglebutton_activate N and Stop N
            Start_N = F_start / 50e6;
            Stop_N = F_stop / 50e6;
            Start_N_int = floor(Start_N);
            Start_N_frac = Start_N - Start_N_int;
            % Stop_N_int = floor(Stop_N);
            % Stop_N_frac = Stop_N - Stop_N_int;
            Reg_03h = Start_N_int;
            Reg_04h = round(Start_N_frac * 2^24);
            
            % number of reference cyles in Tramp
            Nbr_of_Steps = Tramp / Tref;
            
            % desired N step size, given togglebutton_activate N, Stop N and Nbr of steps
            N_Step_Size_desired = (Stop_N - Start_N) / Nbr_of_Steps;
            
            % Quantize the fractional N step into the 24 bit step size
            Frac_N_Step_Size = round(N_Step_Size_desired * 2^24);
            Reg_0Ah = Frac_N_Step_Size;
            
            % Readjust the stop frequency to ensure it falls exactly on a step boundary
            % Target an accurate stop frequency, at the expense of sweep time accuracy
            % Given step size of Frac_N_step_Size/2^24, how many cycles to get from
            % togglebutton_activate N to Stop N
            Nbr_of_Steps = round((Stop_N - Start_N)/(Frac_N_Step_Size/2^24));
            
            % Stop N in real
            % Stop_N_real = Start_N + Nbr_of_Steps * Frac_N_Step_Size / 2^24;
            
            % Number of big steps
            Nbr_of_Big_Steps = floor(Nbr_of_Steps*Frac_N_Step_Size/2^24);
            Reg_0Ch = Start_N_int + Nbr_of_Big_Steps;
            Reg_0Dh =  mod(Nbr_of_Steps*Frac_N_Step_Size, 2^24) + Reg_04h;
            
            if Reg_0Dh > 2^24
                Reg_0Ch = Reg_0Ch + 1;
                Reg_0Dh = Reg_0Dh - 2^24;
            end
            
            app.setSweepstop_Hi = app.setSweepstop_Hi_h + floor(Sweep_N/2^8);
            app.setSweepstop_Lo = app.setSweepstop_Lo_h + mod(Sweep_N, 2^8);
            
            app.setReg03_Hi = app.setReg03_Hi_h + floor(Reg_03h/2^16);
            tmp = mod(Reg_03h, 2^16);
            app.setReg03_Mi = app.setReg03_Mi_h + floor(tmp/2^8);
            app.setReg03_Lo = app.setReg03_Lo_h + mod(tmp, 2^8);
            
            app.setReg04_Hi = app.setReg04_Hi_h + floor(Reg_04h/2^16);
            tmp = mod(Reg_04h, 2^16);
            app.setReg04_Mi = app.setReg04_Mi_h + floor(tmp/2^8);
            app.setReg04_Lo = app.setReg04_Lo_h + mod(tmp, 2^8);
            
            app.setReg0A_Hi = app.setReg0A_Hi_h + floor(Reg_0Ah/2^16);
            tmp = mod(Reg_0Ah, 2^16);
            app.setReg0A_Mi = app.setReg0A_Mi_h + floor(tmp/2^8);
            app.setReg0A_Lo = app.setReg0A_Lo_h + mod(tmp, 2^8);
            
            app.setReg0C_Hi = app.setReg0C_Hi_h + floor(Reg_0Ch/2^16);
            tmp = mod(Reg_0Ch, 2^16);
            app.setReg0C_Mi = app.setReg0C_Mi_h + floor(tmp/2^8);
            app.setReg0C_Lo = app.setReg0C_Lo_h + mod(tmp, 2^8);
            
            app.setReg0D_Hi = app.setReg0D_Hi_h + floor(Reg_0Dh/2^16);
            tmp = mod(Reg_0Dh, 2^16);
            app.setReg0D_Mi = app.setReg0D_Mi_h + floor(tmp/2^8);
            app.setReg0D_Lo = app.setReg0D_Lo_h + mod(tmp, 2^8);
            
            % command setup
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Combining all registers into one commnand doesn't work. We
            % have to set each register one by one.
            
            %             app.cmd_setPll = uint16(zeros(app.cmd_len, 1));
            %             app.cmd_setPll(1:app.cmd_rep) = app.setSweepstop_Hi;
            %             app.cmd_setPll(app.cmd_rep+1:app.cmd_rep*2) = app.setSweepstop_Lo;
            %             app.cmd_setPll(app.cmd_rep*2+1:app.cmd_rep*3) = app.setReg03_Hi;
            %             app.cmd_setPll(app.cmd_rep*3+1:app.cmd_rep*4) = app.setReg03_Mi;
            %             app.cmd_setPll(app.cmd_rep*4+1:app.cmd_rep*5) = app.setReg03_Lo;
            %             app.cmd_setPll(app.cmd_rep*5+1:app.cmd_rep*6) = app.setReg04_Hi;
            %             app.cmd_setPll(app.cmd_rep*6+1:app.cmd_rep*7) = app.setReg04_Mi;
            %             app.cmd_setPll(app.cmd_rep*7+1:app.cmd_rep*8) = app.setReg04_Lo;
            %             app.cmd_setPll(app.cmd_rep*8+1:app.cmd_rep*9) = app.setReg0A_Hi;
            %             app.cmd_setPll(app.cmd_rep*9+1:app.cmd_rep*10) = app.setReg0A_Mi;
            %             app.cmd_setPll(app.cmd_rep*10+1:app.cmd_rep*11) = app.setReg0A_Lo;
            %             app.cmd_setPll(app.cmd_rep*11+1:app.cmd_rep*12) = app.setReg0C_Hi;
            %             app.cmd_setPll(app.cmd_rep*12+1:app.cmd_rep*13) = app.setReg0C_Mi;
            %             app.cmd_setPll(app.cmd_rep*13+1:app.cmd_rep*14) = app.setReg0C_Lo;
            %             app.cmd_setPll(app.cmd_rep*14+1:app.cmd_rep*15) = app.setReg0D_Hi;
            %             app.cmd_setPll(app.cmd_rep*15+1:app.cmd_rep*16) = app.setReg0D_Mi;
            %             app.cmd_setPll(app.cmd_rep*16+1:app.cmd_rep*17) = app.setReg0D_Lo;
            %
            %             sdradar_send_data(app.cmd_setPll);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.cmd_setSweepstop_Hi = uint16(zeros(app.cmd_len, 1));
            app.cmd_setSweepstop_Hi(1:app.cmd_rep) = app.setSweepstop_Hi;
            
            app.cmd_setSweepstop_Lo = uint16(zeros(app.cmd_len, 1));
            app.cmd_setSweepstop_Lo(1:app.cmd_rep) = app.setSweepstop_Lo;
            
            app.cmd_setReg03_Hi = uint16(zeros(app.cmd_len, 1));
            app.cmd_setReg03_Hi(1:app.cmd_rep) = app.setReg03_Hi;
            
            app.cmd_setReg03_Mi = uint16(zeros(app.cmd_len, 1));
            app.cmd_setReg03_Mi(1:app.cmd_rep) = app.setReg03_Mi;
            
            app.cmd_setReg03_Lo = uint16(zeros(app.cmd_len, 1));
            app.cmd_setReg03_Lo(1:app.cmd_rep) = app.setReg03_Lo;
            
            app.cmd_setReg04_Hi = uint16(zeros(app.cmd_len, 1));
            app.cmd_setReg04_Hi(1:app.cmd_rep) = app.setReg04_Hi;
            
            app.cmd_setReg04_Mi = uint16(zeros(app.cmd_len, 1));
            app.cmd_setReg04_Mi(1:app.cmd_rep) = app.setReg04_Mi;
            
            app.cmd_setReg04_Lo = uint16(zeros(app.cmd_len, 1));
            app.cmd_setReg04_Lo(1:app.cmd_rep) = app.setReg04_Lo;
            
            app.cmd_setReg0A_Hi = uint16(zeros(app.cmd_len, 1));
            app.cmd_setReg0A_Hi(1:app.cmd_rep) = app.setReg0A_Hi;
            
            app.cmd_setReg0A_Mi = uint16(zeros(app.cmd_len, 1));
            app.cmd_setReg0A_Mi(1:app.cmd_rep) = app.setReg0A_Mi;
            
            app.cmd_setReg0A_Lo = uint16(zeros(app.cmd_len, 1));
            app.cmd_setReg0A_Lo(1:app.cmd_rep) = app.setReg0A_Lo;
            
            app.cmd_setReg0C_Hi = uint16(zeros(app.cmd_len, 1));
            app.cmd_setReg0C_Hi(1:app.cmd_rep) = app.setReg0C_Hi;
            
            app.cmd_setReg0C_Mi = uint16(zeros(app.cmd_len, 1));
            app.cmd_setReg0C_Mi(1:app.cmd_rep) = app.setReg0C_Mi;
            
            app.cmd_setReg0C_Lo = uint16(zeros(app.cmd_len, 1));
            app.cmd_setReg0C_Lo(1:app.cmd_rep) = app.setReg0C_Lo;
            
            app.cmd_setReg0D_Hi = uint16(zeros(app.cmd_len, 1));
            app.cmd_setReg0D_Hi(1:app.cmd_rep) = app.setReg0D_Hi;
            
            app.cmd_setReg0D_Mi = uint16(zeros(app.cmd_len, 1));
            app.cmd_setReg0D_Mi(1:app.cmd_rep) = app.setReg0D_Mi;
            
            app.cmd_setReg0D_Lo = uint16(zeros(app.cmd_len, 1));
            app.cmd_setReg0D_Lo(1:app.cmd_rep) = app.setReg0D_Lo;
            
            sdradar_send_data(app.cmd_setSweepstop_Hi);
            sdradar_send_data(app.cmd_setSweepstop_Lo);
            sdradar_send_data(app.cmd_setReg03_Hi);
            sdradar_send_data(app.cmd_setReg03_Mi);
            sdradar_send_data(app.cmd_setReg03_Lo);
            sdradar_send_data(app.cmd_setReg04_Hi);
            sdradar_send_data(app.cmd_setReg04_Mi);
            sdradar_send_data(app.cmd_setReg04_Lo);
            sdradar_send_data(app.cmd_setReg0A_Hi);
            sdradar_send_data(app.cmd_setReg0A_Mi);
            sdradar_send_data(app.cmd_setReg0A_Lo);
            sdradar_send_data(app.cmd_setReg0C_Hi);
            sdradar_send_data(app.cmd_setReg0C_Mi);
            sdradar_send_data(app.cmd_setReg0C_Lo);
            sdradar_send_data(app.cmd_setReg0D_Hi);
            sdradar_send_data(app.cmd_setReg0D_Mi);
            sdradar_send_data(app.cmd_setReg0D_Lo);
        end
        
        
        function bw_update(app)
            % Used to update FMCW bandwidth
            app.fStart = app.RampstartGHzSlider.Value*1e9;
            app.fStop = app.RampstopGHzSlider.Value*1e9;
            app.RampstartGHzEditField.Value = app.RampstartGHzSlider.Value;
            app.RampstopGHzEditField.Value = app.RampstopGHzSlider.Value;
            if app.fStart == app.fStop
                app.ModulationDropDown.Value = 'CW';
                app.cmd_setMod = uint16(zeros(app.cmd_len, 1));
                app.cmd_setMod(1:app.cmd_rep) = app.setMod_h + app.modOpt.CW;
                
                app.TabGroup_2.SelectedTab = app.TabGroup_2.Children(4);
            else
                app.ModulationDropDown.Value = 'FMCW Sawtooth';
                app.cmd_setMod = uint16(zeros(app.cmd_len, 1));
                app.cmd_setMod(1:app.cmd_rep) = app.setMod_h + app.modOpt.FMCW_sawtooth;
                
                app.TabGroup_2.SelectedTab = app.TabGroup_2.Children(1);
            end
            sdradar_send_data(app.cmd_setMod);
            app.PLL_register_setup;
            
            plot_rebuild(app, 1);
            update_calculated_param(app);
        end
        
        function SamplesPerSweepOptionUpdate(app)
            app.SamplesPerSweepDropDown.Items ={num2str(app.nSamp_max/8), num2str(app.nSamp_max/4), num2str(app.nSamp_max/2), num2str(app.nSamp_max)};
            app.nSamp = app.nSamp_max/8;
            app.SamplesPerSweepDropDown.Value = num2str(app.nSamp);
        end
        
        function complex_data = get_complex_data_CHx(app, num_of_sweeps)
            % Input:
            % num_of_sweeps: number of sweeps needed
            %
            % Output:
            % complex_data: complex samples requested
            
            % * 2: I and Q two channels
            % Take eatra sweeps to search for the first header and make sure it's a
            % multiple of 512
            data_length = ceil((num_of_sweeps+10) * app.nSamp * 2 * app.nRx * app.nTx/ app.USB_BUFFER_BLOCK_SIZE)*app.USB_BUFFER_BLOCK_SIZE + app.USB_BUFFER_LEFTOVER;
            rawdata = sdradar_get_data(data_length);
            
            % Discard usb leftover data samples
            rawdata = double(rawdata(app.USB_BUFFER_LEFTOVER:end));
            
            % Data check and remove headers
            if app.nTx == 2 % 2 active Tx case
                ind_Tx1 = find(rawdata>=49152);
                ind_Tx1_diff = diff(ind_Tx1);
                ind_Tx2 = find(rawdata<49152 & rawdata>=32768);
                ind_Tx2_diff = diff(ind_Tx2);
                
                if any(ind_Tx1_diff ~= app.nSamp*app.nRx*2*2) || isempty(ind_Tx1) % *2: I&Q; *2: 2 Tx
                    app.MessageEditField.Value = 'Data lost occured in Tx 1';
                    app.MessageLamp.Color = 'red';
                    complex_data = zeros(num_of_sweeps*app.nSamp*2*app.nRx,1)+1i*zeros(num_of_sweeps*app.nSamp*2*app.nRx,1);
                    return
                else
                    app.MessageEditField.Value = 'Connected';
                    app.MessageLamp.Color = 'green';
                end
                
                if any(ind_Tx2_diff ~= app.nSamp*app.nRx*2*2) || isempty(ind_Tx2) % *2: I&Q; *2: 2 Tx
                    app.MessageEditField.Value = 'Data lost occured in Tx 2';
                    app.MessageLamp.Color = 'red';
                    complex_data = zeros(num_of_sweeps*app.nSamp*2*app.nRx,1)+1i*zeros(num_of_sweeps*app.nSamp*2*app.nRx,1);
                    return
                else
                    app.MessageEditField.Value = 'Connected';
                    app.MessageLamp.Color = 'green';
                end
                
                % remove headers
                rawdata(ind_Tx1) = rawdata(ind_Tx1)-49152;
                rawdata(ind_Tx2) = rawdata(ind_Tx2)-32768;
                
                raw = rawdata(ind_Tx1(1):ind_Tx2(end-1)+app.nSamp*2*app.nRx-1); % *2: I&Q
                
                %raw = rawdata(ind_Tx1(1):ind_Tx2(end-1)+app.nSamp*app.nRx*app.nTx*app.PN*2-1);
                
                raw_m = reshape(raw, app.nSamp*2*app.nRx,[]);
                raw_Tx1_m = raw_m(:, 1:2:num_of_sweeps*2); % I1,Q1, I2,Q2,..., I4,Q4
                raw_Tx2_m = raw_m(:, 2:2:num_of_sweeps*2);
                
            else % 1 active Tx case
                ind_Tx1 = find(rawdata>=49152);
                ind_Tx1_diff = diff(ind_Tx1);
                
                if ~any(ind_Tx1_diff ~= app.nSamp*app.nRx*2) % *2: I&Q;
                    app.MessageEditField.Value = 'Connected';
                    app.MessageLamp.Color = 'green';
                else
                    app.MessageEditField.Value = 'Data lost occured in Tx 2';
                    app.MessageLamp.Color = 'red';
                end
                
                % remove sweep headers
                rawdata(ind_Tx1) = rawdata(ind_Tx1) - 49152;
            end
            
            % Readjust the first sample to the beginning of the first sweep and format to complex
            try
                RxSelected = app.ViewingRxDropDown.Value;
                
                if app.nTx == 1
                    if app.nRx == 4
                        switch RxSelected
                            case '1'
                                complex_data = rawdata(ind_Tx1(1)+1:app.nRx*2:ind_Tx1(1)+num_of_sweeps*app.nSamp*app.nRx*2) + ...
                                    rawdata(ind_Tx1(1):2*app.nRx:ind_Tx1(1)+num_of_sweeps*app.nSamp*2*app.nRx-1)*1i;
                            case '2'
                                complex_data = rawdata(ind_Tx1(1)+3:app.nRx*2:ind_Tx1(1)+num_of_sweeps*app.nSamp*app.nRx*2) + ...
                                    rawdata(ind_Tx1(1)+2:2*app.nRx:ind_Tx1(1)+num_of_sweeps*app.nSamp*2*app.nRx-1)*1i;
                            case '3'
                                complex_data = rawdata(ind_Tx1(1)+5:app.nRx*2:ind_Tx1(1)+num_of_sweeps*app.nSamp*app.nRx*2) + ...
                                    rawdata(ind_Tx1(1)+4:2*app.nRx:ind_Tx1(1)+num_of_sweeps*app.nSamp*2*app.nRx-1)*1i;
                            case '4'
                                complex_data = rawdata(ind_Tx1(1)+7:app.nRx*2:ind_Tx1(1)+num_of_sweeps*app.nSamp*app.nRx*2) + ...
                                    rawdata(ind_Tx1(1)+6:2*app.nRx:ind_Tx1(1)+num_of_sweeps*app.nSamp*2*app.nRx-1)*1i;
                        end
                    elseif app.nRx == 2
                        switch RxSelected
                            case {'1','3'}
                                complex_data = rawdata(ind_Tx1(1)+1:app.nRx*2:ind_Tx1(1)+num_of_sweeps*app.nSamp*app.nRx*2) + ...
                                    rawdata(ind_Tx1(1):2*app.nRx:ind_Tx1(1)+num_of_sweeps*app.nSamp*2*app.nRx-1)*1i;
                            case {'2','4'}
                                complex_data = rawdata(ind_Tx1(1)+3:app.nRx*2:ind_Tx1(1)+num_of_sweeps*app.nSamp*app.nRx*2) + ...
                                    rawdata(ind_Tx1(1)+2:2*app.nRx:ind_Tx1(1)+num_of_sweeps*app.nSamp*2*app.nRx-1)*1i;
                        end
                    elseif app.nRx == 1
                        complex_data = rawdata(ind_Tx1(1)+1:app.nRx*2:ind_Tx1(1)+num_of_sweeps*app.nSamp*app.nRx*2) + ...
                            rawdata(ind_Tx1(1):2*app.nRx:ind_Tx1(1)+num_of_sweeps*app.nSamp*2*app.nRx-1)*1i;
                    end
                    
                else % Tx2 exists
                    TxSelected = app.ViewingTxDropDown.Value;
                    
                    switch TxSelected
                        case '1'
                            raw_Tx1 = raw_Tx1_m(:);
                            if app.nRx == 4
                                switch RxSelected
                                    case '1'
                                        complex_data = 1i*raw_Tx1(1:app.nRx*2:num_of_sweeps*app.nSamp*app.nRx*2)+ ...
                                            raw_Tx1(2:app.nRx*2:1+num_of_sweeps*app.nSamp*app.nRx*2);
                                    case '2'
                                        complex_data = 1i*raw_Tx1(3:app.nRx*2:2+num_of_sweeps*app.nSamp*app.nRx*2)+ ...
                                            raw_Tx1(4:app.nRx*2:3+num_of_sweeps*app.nSamp*app.nRx*2);
                                    case '3'
                                        complex_data = 1i*raw_Tx1(5:app.nRx*2:4+num_of_sweeps*app.nSamp*app.nRx*2)+ ...
                                            raw_Tx1(6:app.nRx*2:5+num_of_sweeps*app.nSamp*app.nRx*2);
                                    case '4'
                                        complex_data = 1i*raw_Tx1(7:app.nRx*2:6+num_of_sweeps*app.nSamp*app.nRx*2)+ ...
                                            raw_Tx1(8:app.nRx*2:7+num_of_sweeps*app.nSamp*app.nRx*2);
                                end
                            elseif app.nRx == 2
                                switch RxSelected
                                    case {'1','3'}
                                        complex_data = 1i*raw_Tx1(1:app.nRx*2:num_of_sweeps*app.nSamp*app.nRx*2)+ ...
                                            raw_Tx1(2:app.nRx*2:1+num_of_sweeps*app.nSamp*app.nRx*2);
                                    case {'2','4'}
                                        complex_data = 1i*raw_Tx1(3:app.nRx*2:2+num_of_sweeps*app.nSamp*app.nRx*2)+ ...
                                            raw_Tx1(4:app.nRx*2:3+num_of_sweeps*app.nSamp*app.nRx*2);
                                end
                            elseif app.nRx == 1
                                complex_data = 1i*raw_Tx1(1:app.nRx*2:num_of_sweeps*app.nSamp*app.nRx*2)+ ...
                                    raw_Tx1(2:app.nRx*2:1+num_of_sweeps*app.nSamp*app.nRx*2);
                            end
                            
                        case '2'
                            raw_Tx2 = raw_Tx2_m(:);
                            if app.nRx == 4
                                switch RxSelected
                                    case '1'
                                        complex_data = 1i*raw_Tx2(1:app.nRx*2:num_of_sweeps*app.nSamp*app.nRx*2)+ ...
                                            raw_Tx2(2:app.nRx*2:1+num_of_sweeps*app.nSamp*app.nRx*2);
                                    case '2'
                                        complex_data = 1i*raw_Tx2(3:app.nRx*2:2+num_of_sweeps*app.nSamp*app.nRx*2)+ ...
                                            raw_Tx2(4:app.nRx*2:3+num_of_sweeps*app.nSamp*app.nRx*2);
                                    case '3'
                                        complex_data = 1i*raw_Tx2(5:app.nRx*2:4+num_of_sweeps*app.nSamp*app.nRx*2)+ ...
                                            raw_Tx2(6:app.nRx*2:5+num_of_sweeps*app.nSamp*app.nRx*2);
                                    case '4'
                                        complex_data = 1i*raw_Tx2(7:app.nRx*2:6+num_of_sweeps*app.nSamp*app.nRx*2)+ ...
                                            raw_Tx2(8:app.nRx*2:7+num_of_sweeps*app.nSamp*app.nRx*2);
                                end
                            elseif app.nRx == 2
                                switch RxSelected
                                    case {'1','3'}
                                        complex_data = 1i*raw_Tx2(1:app.nRx*2:num_of_sweeps*app.nSamp*app.nRx*2)+ ...
                                            raw_Tx2(2:app.nRx*2:1+num_of_sweeps*app.nSamp*app.nRx*2);
                                    case {'2','4'}
                                        complex_data = 1i*raw_Tx2(3:app.nRx*2:2+num_of_sweeps*app.nSamp*app.nRx*2)+ ...
                                            raw_Tx2(4:app.nRx*2:3+num_of_sweeps*app.nSamp*app.nRx*2);
                                end
                            elseif app.nRx == 1
                                complex_data = 1i*raw_Tx2(1:app.nRx*2:num_of_sweeps*app.nSamp*app.nRx*2)+ ...
                                    raw_Tx2(2:app.nRx*2:1+num_of_sweeps*app.nSamp*app.nRx*2);
                            end
                            
                    end
                end
            catch
                complex_data = zeros(num_of_sweeps*app.nSamp*2*app.nRx,1)+1i*zeros(num_of_sweeps*app.nSamp*2*app.nRx,1);
                return;
            end
        end
        
        function control_element_enable(app, enabled)
            % enabled: 'on' | 'off'
            app.SavecurrentconfigMenu.Enable = enabled;
            app.LoadapreviousconfigMenu.Enable = enabled;
            
            app.RampstartGHzSlider.Enable = enabled;
            app.RampstopGHzSlider.Enable = enabled;
            
            if strcmp(enabled, 'off')
                app.Rx1CheckBox.Enable = enabled;
                app.Rx2CheckBox.Enable = enabled;
                app.Rx3CheckBox.Enable = enabled;
                app.Rx4CheckBox.Enable = enabled;
                app.Tx1CheckBox.Enable = enabled;
                app.Tx2CheckBox.Enable = enabled;
            else
                switch app.model_s
                    case "SDR-KIT 2400T2R4"
                        app.Rx1CheckBox.Enable = 'on';
                        app.Rx2CheckBox.Enable = 'on';
                        app.Rx3CheckBox.Enable = 'on';
                        app.Rx4CheckBox.Enable = 'on';
                        app.Tx1CheckBox.Enable = 'on';
                        app.Tx2CheckBox.Enable = 'on';
                end
            end
            
            % app.ModulationDropDown.Enable = enabled;
            app.SamplesPerSweepDropDown.Enable = enabled;
            app.SweeptimeusDropDown.Enable = enabled;
            
            app.RecordButton.Enable = enabled;
        end
        
        function  plot_rebuild(app, toggleState)
            app.time_scope_rebuild = toggleState;
            app.range_scope_rebuild = toggleState;
            app.velocity_scope_rebuild = toggleState;
            app.range_waterfall_rebuild = toggleState;
            app.velocity_waterfall_rebuild = toggleState;
            app.range_doppler_rebuild = toggleState;
            
            app.spectrogram_scope_rebuild = toggleState;
            app.mean_doppler_shift_rebuild = toggleState;
        end
        
        
        function update_calculated_param(app)
            app.bw = app.fStop - app.fStart;
            app.fc = app.fStart + app.bw/2;
            app.sampRate = 1/(app.sTime)*app.nSamp;
            
            switch app.ModulationDropDown.Value
                case 'FMCW Sawtooth'
                    app.prf = 1/(app.sTime)/app.nTx;
                    app.rngMax = 3e8/(2*app.bw)*app.nSamp/2;
                    app.velMax = app.prf/2*3e8/app.fc/2;
                case 'CW'
                    samp_rate_dec = app.sampRate/app.dec;
                    Doppler_max = samp_rate_dec/2;
                    app.velMax = Doppler_max*3e8/app.fc;
                    
                    if app.nTx == 2
                        % Prevent multiple transmitter from operating in CW mode
                        app.cmd_setTx = uint16(zeros(app.cmd_len, 1));
                        app.cmd_setTx(1:app.cmd_rep) = app.setTx_h + app.txOpt.ch1;
                        app.Tx2CheckBox.Value = 0;
                        app.nTx = 1;
                        app.ViewingTxDropDown.Items = {'1'};
                        sdradar_send_data(app.cmd_setTx);
                    end
            end
        end


        function results = checkIfDirExist(app)
            
            % This function only creates a folder for continuouslsy
            % recoding the ADC samples in the files. 
                        
            app.stream_save_dir = strcat(pwd, '\', 'ADC_streamedData');
            
            if ~exist(app.stream_save_dir, 'dir')
                mkdir(app.stream_save_dir);
                
                results = 1;
                
            elseif (exist(app.stream_save_dir, 'dir'))  
                
                results = 1;
                
            else
                
                results = 0;
            end
                        
        end
    end
    
    methods (Access = public)
        
        function saveData(app,complexData)
            
            data2Write = [real(complexData), imag(complexData)];
            
            out_file_name = strcat(app.stream_save_dir,'\',string(datetime('now','Format','d-MMM-y_HH-mm-ss')), '.mat');
            
            start(MyJavaThread_2(out_file_name,data2Write));
            
        end
        
        function saveData_2(app, data_matrix)
            
            out_file_name = strcat(app.stream_save_dir,'\',string(datetime('now','Format','d-MMM-y_HH-mm-ss')));
            data_2_save = [real(data_matrix), imag(data_matrix)];
            writeNPY(data_2_save, strcat(out_file_name, '.npy'));
            
            %matFile = strcat(out_file_name, '.mat');
            %save(matFile, 'data_matrix', '-v7.3','-nocompression');
            
        end
        
        function plotting (app, complex_data)
            
            if strcmp(app.ModulationDropDown.Value, 'FMCW Sawtooth')
                
                % Upper tab group selection
                switch app.TabGroup.SelectedTab.Title
                    %                    case 'Time Scope'
                    %                         try
                    %                             datastream = complex_data(1:1+app.N_avg*app.nSamp-1);
                    %                             % Time-Scope display
                    %                             IData = real(datastream); % I data
                    %                             QData = imag(datastream); % Q data
                    %                             IMatrix = reshape(IData,app.nSamp,app.N_avg);
                    %                             QMatrix = reshape(QData,app.nSamp,app.N_avg);
                    %                             I_rawdata = mean(IMatrix,2);
                    %                             Q_rawdata = mean(QMatrix,2);
                    %
                    %                             if app.time_scope_rebuild
                    %                                 app.UIAxes_timeScope.cla;
                    %                                 Xaxis = 1:app.nSamp;
                    %                                 Yaxis = NaN(2, app.nSamp);
                    %                                 app.hLine_time_scope = line(app.UIAxes_timeScope, Xaxis, Yaxis, 'LineWidth', 3);
                    %                                 app.hLine_time_scope(1).Color = [0 1 0];
                    %                                 app.hLine_time_scope(2).Color = [1 1 0];
                    %                                 xlabel(app.UIAxes_timeScope,'Samples per sweep');
                    %                                 ylabel(app.UIAxes_timeScope,'Amplitude');
                    %                                 set(app.UIAxes_timeScope, 'XGrid','off','YGrid', 'on', 'GridColor', [0.23 0.44 0.34], 'GridLineStyle', '--', 'GridAlpha', 0.8)
                    %                                 set(app.UIAxes_timeScope,'Color','Black');
                    %                                 legend(app.UIAxes_timeScope, {'I-Channel', 'Q-Channel'},'FontSize', 12, 'TextColor', 'k',...
                    %                                     'Color',[0.65,0.65,0.65]); % Create the legend
                    %                                 axis(app.UIAxes_timeScope, [1 app.nSamp -inf inf]);
                    %                                 drawnow;
                    %
                    %                                 app.time_scope_rebuild = 0;
                    %                             else
                    %                                 app.hLine_time_scope(1).YData = I_rawdata - mean(I_rawdata);
                    %                                 app.hLine_time_scope(2).YData = Q_rawdata - mean(Q_rawdata);
                    %                                 drawnow
                    %                             end
                    %
                    %                         catch
                    %
                    %                         end
                    
                    case 'Spectrogram'
                        
                        app.SaveRealtimestreamCheckBox.Enable = 'on';
                        tic;
                        % Case when user has selected the Spectrogram Tab
                        
                        try
                            % Complex_data seems to be a one dimensional [Check it with device]
                            data_matrix = reshape(complex_data, app.nSamp, []);
                            data_matrix = bsxfun(@minus, data_matrix, mean(data_matrix, 2)); % remove static objects
                            
                            %% Save complex stream data to the file
                            
                            %if (app.saveStream)
                            
                            %saveData(app, complex_data);
                            %saveData_2(app, data_matrix);
                            
                            %end
                            % if PCA checkbox is selected.
                            if(app.PCACheckBox.Value)
                                
                                % Check dimensions. If num colums is
                                % less than num rows PCA can not be
                                % applied.
                                if(size(data_matrix,2) <= app.nSamp)
                                    % PCA can not be applied just sum
                                    % over all frequencies.
                                    x = (sum(tmp, 1));
                                else
                                    % Apply Prinicipal Component analysis
                                    [coeff, score, ~, ~, explained, ~] = pca(data_matrix');
                                    
                                    %Check how many components user has
                                    %selected.
                                    if(app.numPCA_components <= 1)
                                        
                                        x = conj(score(:,1)');
                                    else
                                        % Note that transpose is applied
                                        % after computing the sum.
                                        x = conj(sum(score(:,1:app.numPCA_components),2)');
                                    end
                                end
                                
                            else % if PCA checkbox is not selected.
                                
                                x = conj(sum(data_matrix, 1));
                                
                            end
                            
                            if(app.HighPassFilterCheckBox.Value)
                                
                                ns = size(x,2);
                                [b,a] = butter(3,0.05, 'high');
                                [h, f1] = freqz(b, a, ns);
                                
                                x = filtfilt(b,a,x(1:ns));
                                
                            end
                            
                            Fs = 1/(app.nTx*app.sTime);
                            [STFT,f,t] = spectrogram(x,kaiser(64,10),63,512,Fs,'centered','yaxis');
                            Sxx = abs(STFT).^2;
                            Sxx_norm = Sxx./sum(Sxx,1);
                            
                            %Now Add code to plot the spectrogram
                            if app.spectrogram_scope_rebuild
                                
                                cla(app.UIAxes_spectrogramScope);
                                
                                colormap(app.UIAxes_spectrogramScope, 'hot');
                                shading(app.UIAxes_spectrogramScope, 'interp');
                                xlabel(app.UIAxes_spectrogramScope,'Time (s)');
                                ylabel(app.UIAxes_spectrogramScope,'Frequency (Hz)');
                                app.UIAxes_spectrogramScope.View(2);
                                axis(app.UIAxes_spectrogramScope, [t(1), t(end), -500, 500]);
                                Xaxis = t;
                                Yaxis = f;
                                Zaxis = Sxx_norm;
                                app.hSurf_spectrogram_scope = imagesc(app.UIAxes_spectrogramScope, Xaxis, Yaxis, Zaxis);
                                drawnow;
                                
                                app.spectrogram_scope_rebuild = 0;
                            else
                                app.hSurf_spectrogram_scope.CData = Sxx_norm;
                                drawnow;
                            end


                            toc;
                        catch
                            
                        end
                        
                    case 'Mean Doppler Shift'
                        app.SaveRealtimestreamCheckBox.Enable = 'on';
                        % Case when user has selected the mean Doppler
                        % Shift Tab
                        tic;
                        try
                            data_matrix = reshape(complex_data, app.nSamp, []);
                            data_matrix = bsxfun(@minus, data_matrix, mean(data_matrix, 2)); % remove static objects
                            
                            
                            %if(app.saveStream)
                            
                            %saveData(app, complex_data);
                            %saveData_2(app, data_matrix);
                            %end
                            
                            % if PCA checkbox is selected.
                            %                             if(app.PCACheckBox.Value)
                            %
                            %                                 % Check dimensions. If num colums is
                            %                                 % less than num rows PCA can not be
                            %                                 % applied.
                            %                                 if(app.PN <= app.nSamp)
                            %                                     % PCA can not be applied just sum
                            %                                     % over all frequencies.
                            %                                     x = conj(sum(tmp, 1));
                            %                                 else
                            %                                     % Apply Prinicipal Component analysis
                            %                                     [coeff, score, ~, ~, explained, ~] = pca(data_matrix');
                            %
                            %                                     %Check how many components user has
                            %                                     %selected.
                            %                                     if(app.numPCA_components <= 1)
                            %
                            %                                         x = conj(score(:,1)');
                            %
                            %                                     else
                            %                                         % Note that transpose is applied
                            %                                         % after computing the sum.
                            %                                         x = conj(sum(score(:,1:app.numPCA_components),2)');
                            %
                            %                                     end
                            %                                 end
                            %
                            %                             else % if PCA checkbox is not selected.
                            
                            x = conj(sum(data_matrix, 1));
                            
                            %                             end
                            %
                            %                             if(app.HighPassFilterCheckBox.Value)
                            %                                 ns = size(x,2);
                            %                                 [b,a] = butter(3,0.05, 'high');
                            %                                 [h, f1] = freqz(b, a, ns);
                            %                                 x = filtfilt(b,a,x(1:ns));
                            %
                            %                             end
                            
                            Fs = 1/(app.nTx*app.sTime);
                            [STFT,f,t] = spectrogram(x,kaiser(64,10),63,512,Fs,'centered','yaxis');
                            Sxx = abs(STFT).^2;
                            Sxx_norm = Sxx./sum(Sxx,1);
                            mean_Exact_Radar = sum(f.*Sxx_norm,1)./sum(Sxx_norm,1);
                            
                            if app.mean_doppler_shift_rebuild
                                
                                cla(app.UIAxes_meanDopplerShiftScope);
                                
                                Xaxis = t;
                                Yaxis = sgolayfilt(mean_Exact_Radar,3,51) ;
                                app.hLine_mean_doppler_shift_scope = line(app.UIAxes_meanDopplerShiftScope, Xaxis, Yaxis, 'LineWidth', 2);
                                app.hLine_mean_doppler_shift_scope.Color = [0 1 0];
                                xlabel(app.UIAxes_meanDopplerShiftScope,'Time (s)');
                                ylabel(app.UIAxes_meanDopplerShiftScope,'Frequency (Hz)');
                                set(app.UIAxes_meanDopplerShiftScope, 'XGrid','off','YGrid', 'on', 'GridColor', [0.23 0.44 0.34], 'GridLineStyle', '--', 'GridAlpha', 0.8);
                                set(app.UIAxes_meanDopplerShiftScope,'Color','Black');
                                axis(app.UIAxes_meanDopplerShiftScope, [t(1), t(end), -500, 500]);
                                drawnow;
                                
                                app.mean_doppler_shift_rebuild = 0;
                            else
                                app.hLine_mean_doppler_shift_scope.YData  = mean_Exact_Radar;
                                drawnow;
                            end
                            toc;
                        catch
                            
                        end
                end
                
                % Lower tab group selection
                switch app.TabGroup_2.SelectedTab.Title
                    %                         case 'Range Scope'
                    %                             try
                    %                                 datastream = complex_data(1:1+app.N_avg*app.nSamp-1);
                    %                                 IData = real(datastream); % I data
                    %                                 IMatrix = reshape(IData,app.nSamp,app.N_avg);
                    %                                 I_rawdata = mean(IMatrix,2);
                    %
                    %                                 if app.RangeNotchFilterCheckBox.Value % Range Notch Filter (A bandpass filter)
                    %                                     notchfreq = app.RangeNotchSlider.Value+1;
                    %                                     if notchfreq >= app.sampRate/2.5
                    %                                         notchfreq = app.sampRate/2.5;
                    %                                     end
                    %                                     F1 = 2*notchfreq/app.sampRate;
                    %                                     F2 = 2*app.sampRate/2.1/app.sampRate;
                    %                                     F12 = [F1,F2];
                    %                                     N = 8;
                    %                                     [BF,AF] = butter(N,F12);
                    %                                     I_rawdata = I_rawdata - mean(I_rawdata);
                    %                                     sgn = filter(BF,AF,I_rawdata);
                    %                                 else
                    %                                     sgn = I_rawdata;
                    %                                 end
                    %
                    %                                 Range profile on I Channel stream
                    %                                 sgn = sgn .* hamming(app.nSamp);
                    %                                 df = app.sampRate /  app.Nfftr; % frequency resolution
                    %                                 fsignal_I = fft(sgn, app.Nfftr);
                    %                                 fsignal_I = fsignal_I(1:app.Nfftr/2+1);
                    %                                 Range_psd = (1/df).*abs(fsignal_I).^2;
                    %                                 Range_psd(2:end-1) = 2*Range_psd(2:end-1);
                    %                                 Range_psd = 10*log10(Range_psd + eps); % power spectral density
                    %                                 [Range_psd_max, rng_psd_max_index] = max(Range_psd);
                    %                                 [Range_psd_min, ~] = min(Range_psd);
                    %
                    %                                 rng_axis = linspace(0, app.sampRate/2, app.Nfftr/2+1)*3e8* ...
                    %                                     app.sTime/(2*app.bw);
                    %                                 rng_max = rng_axis(rng_psd_max_index);
                    %
                    %                                 if app.range_scope_rebuild
                    %                                     app.UIAxes_rangeScope.cla;
                    %                                     app.hLine_range_scope = line(app.UIAxes_rangeScope, rng_axis, Range_psd, 'LineWidth', 3);
                    %                                     app.hLine_range_scope.Color = [0 1 0];
                    %                                     axis(app.UIAxes_rangeScope, [0, app.rngMax, 0, max(Range_psd_max, 20)]);
                    %                                     xlabel(app.UIAxes_rangeScope,'Range (meters)');
                    %                                     ylabel(app.UIAxes_rangeScope,'Power/frequency (dB/Hz)');
                    %                                     hold(app.UIAxes_rangeScope,"on");
                    %                                     set(app.UIAxes_rangeScope, 'XGrid','on','YGrid','on','GridColor', [0.23 0.44 0.34], 'GridLineStyle', '--', 'GridAlpha', 0.8, 'Color', 'Black');
                    %                                     app.hLineV_range_scope = line(app.UIAxes_rangeScope, [rng_max rng_max],[-1000 1000], 'LineStyle', '--', 'Color', [1 1 0], 'LineWidth', 3);
                    %                                     label = sprintf('Detected Range: %4.2f m ', rng_max);
                    %                                     app.hText_range_scope = text(app.UIAxes_rangeScope, rng_max+1, Range_psd_max-1, label, 'Color', [1 1 0]);
                    %                                     drawnow
                    %
                    %                                     app.range_scope_rebuild = 0;
                    %                                 else
                    %                                     app.hLine_range_scope.YData = Range_psd;
                    %                                     app.hLineV_range_scope.XData = [rng_max, rng_max];
                    %                                     app.hLineV_range_scope.YData = [Range_psd_min Range_psd_max];
                    %                                     label = sprintf('Detected Range: %4.2f m ', rng_max);
                    %                                     app.hText_range_scope.String = label;
                    %                                     app.hText_range_scope.Position = [rng_max+1, Range_psd_max-1];
                    %                                     axis(app.UIAxes_rangeScope, [0, app.rngMax, 0, max(Range_psd_max,1)]);
                    %                                     drawnow
                    %                                 end
                    %                             catch
                    %                                 continue
                    %                             end
                    
                    %                     case 'Range Doppler'
                    %                         try
                    %                             df_rng = app.sampRate /  app.Nfftr; % frequency resolution in range
                    %                             df_dopp = app.prf / app.Nfftv; % frequency resolution in Doppler
                    %                             data_matrix = reshape(complex_data, app.nSamp, app.PN);
                    %                             data_matrix = bsxfun(@minus, data_matrix, mean(data_matrix, 2)); % remove static objects
                    %                             % Discard down-chirp samples
                    %                             % Sweep_N = getappdata(handles.SDRadar, 'Sweep_N');
                    %                             % rcut = round(Sweep_N/4/dec);
                    %                             % % skip a loop if:
                    %                             % if rcut > handles.NTS
                    %                             %     continue
                    %                             % end
                    %                             % data_matrix = data_matrix(1:rcut, :);
                    %
                    %                             % Filtering in range
                    %                             if app.RangeNotchFilterCheckBox.Value
                    %                                 notchfreq = app.RangeNotchSlider.Value+1;
                    %                                 if notchfreq >= app.sampRate/2.5
                    %                                     notchfreq = app.sampRate/2.5;
                    %                                 end
                    %                                 F1 = 2*notchfreq/app.sampRate;
                    %                                 F2 = 2*app.sampRate/2.1/app.sampRate;
                    %                                 F12 = [F1,F2];
                    %                                 N = 8;
                    %                                 [BF,AF] = butter(N,F12);
                    %                                 data_matrix = filter(BF,AF,data_matrix,[],1);
                    %                             end
                    %
                    %                             % Range profiles
                    %                             Rng_dft = fft(data_matrix, app.Nfftr, 1);
                    %                             Rng_dft = Rng_dft(1:app.Nfftr/2, :);
                    %                             % Rng_dft = bsxfun(@minus, Rng_dft, mean(Rng_dft, 2)); % remove stationary objects
                    %
                    %                             % Filtering in Doppler
                    %                             if app.DopplerNotchFilterCheckBox.Value
                    %                                 notch_vr = app.DopplerNotchSlider.Value+0.01;
                    %                                 notch_doppler = 2*notch_vr/(3e8/app.fc);
                    %                                 stopfreq = notch_doppler;
                    %                                 if stopfreq > app.prf/3
                    %                                     stopfreq = app.prf/3.1;
                    %                                 end
                    %                                 passfreq = 1.5*stopfreq;
                    %                                 ws = stopfreq/(app.prf/2);
                    %                                 wp = passfreq/(app.prf/2);
                    %                                 Rp = 2;
                    %                                 Rs = 30;
                    %                                 [N,Wn] = buttord(wp,ws,Rp,Rs);
                    %                                 [num,den] = butter(N,Wn,'high'); % Highpass filter
                    %                                 Rng_dft = filter(num,den,Rng_dft,[],2); % Doppler Notch filter
                    %                             end
                    %
                    %                             RngDopp_psd = abs(fftshift(fft(Rng_dft,app.Nfftv,2),2)).^2/(df_dopp*df_rng);
                    %
                    %                             if app.range_doppler_rebuild
                    %                                 dopp_axis = linspace(-app.prf/2,app.prf/2,app.Nfftv)*3e8/app.fc/2;
                    %                                 rng_axis = linspace(0, app.sampRate/2, app.Nfftr/2+1)*3e8* ...
                    %                                     app.sTime/(2*app.bw);
                    %                                 app.UIAxes_rangeDoppler.cla;
                    %                                 app.hRngDopp = imagesc(app.UIAxes_rangeDoppler, dopp_axis, rng_axis, 10*log10(RngDopp_psd));
                    %                                 % axis xy
                    %                                 % clim = get(app.UIAxes_rangeDoppler,'CLim');
                    %                                 clim_max = app.DRMaxKnob.Value;
                    %                                 clim_min = app.DRMinKnob.Value;
                    %                                 set(app.UIAxes_rangeDoppler, 'CLim', [clim_min, clim_max]);
                    %                                 axis(app.UIAxes_rangeDoppler, [-app.velMax, app.velMax, 0, app.rngMax])
                    %                                 xlabel(app.UIAxes_rangeDoppler, 'Velocity (m/s)')
                    %                                 ylabel(app.UIAxes_rangeDoppler, 'Range (m)')
                    %                                 legend(app.UIAxes_rangeDoppler, 'off')
                    %                                 shading(app.UIAxes_rangeDoppler, 'interp');
                    %                                 drawnow
                    %
                    %                                 app.range_doppler_rebuild = 0;
                    %                             else
                    %                                 app.hRngDopp.CData =10*log10(RngDopp_psd);
                    %                                 drawnow
                    %                             end
                    %
                    %                             if app.DRAutoCheckBox.Value
                    %                                 RngDopp_psd_dB = 10*log10(RngDopp_psd);
                    %                                 RngDopp_psd_dB(isinf(RngDopp_psd_dB)) = 0;
                    %                                 AutoDRMaxVal = max(max(RngDopp_psd_dB));
                    %                                 AutoDRMaxVal = max(AutoDRMaxVal, 88);
                    %
                    %                                 [row,col] = size(RngDopp_psd_dB);
                    %                                 AutoDRMinVal = sum(sum(RngDopp_psd_dB))/(row*col);
                    %                                 AutoDRMinVal = max(AutoDRMinVal, 40);
                    %
                    %                                 app.DRMaxKnob.Value = AutoDRMaxVal;
                    %                                 app.DRMinKnob.Value = AutoDRMinVal;
                    %                                 % clim = get(gca,'CLim');
                    %                                 set(app.UIAxes_rangeDoppler, 'CLim', [AutoDRMinVal, AutoDRMaxVal]);
                    %                             end
                    %                         catch
                    %
                    %                         end
                    %
                    %                     case 'Range Waterfall'
                    %                         try
                    %                             datastream = complex_data(1:1+app.N_avg*app.nSamp-1);
                    %                             IData = real(datastream); % I data
                    %                             IMatrix = reshape(IData,app.nSamp,app.N_avg);
                    %                             I_rawdata = mean(IMatrix,2);
                    %
                    %                             % Filtering
                    %                             if app.RangeNotchFilterCheckBox.Value % Range Notch Filter (A bandpass filter)
                    %                                 notchfreq = app.RangeNotchSlider.Value+1;
                    %                                 if notchfreq >= app.sampRate/2.5
                    %                                     notchfreq = app.sampRate/2.5;
                    %                                 end
                    %                                 F1 = 2*notchfreq/app.sampRate;
                    %                                 F2 = 2*app.sampRate/2.1/app.sampRate;
                    %                                 F12 = [F1,F2];
                    %                                 N = 8;
                    %                                 [BF,AF] = butter(N,F12);
                    %                                 I_rawdata = I_rawdata - mean(I_rawdata);
                    %                                 sgn = filter(BF,AF,I_rawdata);
                    %                             end
                    %
                    %                             % Range profile on I Channel stream
                    %                             % sgn = sgn .* hamming(handles.NTS);
                    %                             df = app.sampRate/app.Nfftr; % frequency resolution
                    %                             fsignal_I = fft(sgn, app.Nfftr);
                    %                             fsignal_I = fsignal_I(1:app.Nfftr/2+1);
                    %                             Range_psd = (1/df).*abs(fsignal_I).^2;
                    %                             Range_psd(2:end-1) = 2*Range_psd(2:end-1);
                    %                             Range_psd = 10*log10(Range_psd + eps); % power spectral density
                    %
                    %                             if app.Range_WF_flag
                    %                                 m = app.HistorysizeframeSpinner.Value;
                    %                                 Range_WF = zeros(m, app.Nfftr/2+1);
                    %                                 app.Range_WF_flag = 0;
                    %                             end
                    %
                    %                             try
                    %                                 Range_WF(2:m,:) = Range_WF(1:m-1,:);
                    %                                 Range_WF(1,:) = Range_psd;
                    %                             catch
                    %                                 app.Range_WF_flag = 1;
                    %                             end
                    %
                    %                             if app.range_waterfall_rebuild
                    %                                 rng_axis = linspace(0, app.sampRate/2, app.Nfftr/2+1)*3e8* ...
                    %                                     app.sTime/(2*app.bw);
                    %
                    %                                 app.UIAxes_rangeWaterfall.cla;
                    %                                 app.hRange_WF = imagesc(app.UIAxes_rangeWaterfall, rng_axis, (0:m-1), Range_WF);
                    %                                 axis(app.UIAxes_rangeWaterfall, [0 app.rngMax 0 m-1]);
                    %                                 % axis xy
                    %                                 % dynamic range
                    %                                 % clim = get(gca,'CLim');
                    %                                 clim_max = app.DRMaxKnob.Value;
                    %                                 clim_min = app.DRMinKnob.Value;
                    %                                 set(app.UIAxes_rangeWaterfall, 'CLim', [clim_min,clim_max]);
                    %                                 shading(app.UIAxes_rangeWaterfall, 'interp');
                    %                                 xlabel(app.UIAxes_rangeWaterfall,'Range (m)');
                    %                                 ylabel(app.UIAxes_rangeWaterfall,'Frame');
                    %                                 legend(app.UIAxes_rangeWaterfall,'off')
                    %                                 drawnow
                    %
                    %                                 app.range_waterfall_rebuild = 0;
                    %                             else
                    %                                 app.hRange_WF.CData =  Range_WF;
                    %                                 drawnow
                    %                             end
                    %
                    %                             if app.DRAutoCheckBox.Value
                    %                                 Range_WF(isinf(Range_WF)) = 0;
                    %                                 AutoDRMaxVal = max(max(Range_WF));
                    %                                 AutoDRMaxVal = max(AutoDRMaxVal, 88);
                    %
                    %                                 [row,col] = size(Range_WF);
                    %                                 AutoDRMinVal = sum(sum(Range_WF))/(row*col);
                    %                                 AutoDRMinVal = max(AutoDRMinVal, 40);
                    %
                    %                                 app.DRMaxKnob.Value = AutoDRMaxVal;
                    %                                 app.DRMinKnob.Value = AutoDRMinVal;
                    %                                 % clim = get(gca,'CLim');
                    %                                 set(app.UIAxes_rangeWaterfall, 'CLim', [AutoDRMinVal, AutoDRMaxVal]);
                    %                             end
                    %                         catch
                    %
                    %                         end
                    %
                end
            elseif strcmp(app.ModulationDropDown.Value, 'CW')
                switch app.TabGroup.SelectedTab.Title
                    case 'Time Scope'
                        try
                            % The first 2 sweeps do not look good. Dump them
                            complex_data = complex_data(1+10*app.nSamp:end);
                            I_rawdata = real(complex_data) - mean(real(complex_data));
                            Q_rawdata = imag(complex_data) - mean(imag(complex_data));
                            
                            % IQ imbalance correction
                            %                                 miux = mean(I_rawdata);
                            %                                 miuy = mean(Q_rawdata);
                            %                                 I2_bar = mean((I_rawdata-miux).^2);
                            %                                 Q2_bar = mean((Q_rawdata-miuy).^2);
                            %                                 IQ_bar = mean((I_rawdata-miux).*(Q_rawdata-miuy));
                            %                                 D_bar = IQ_bar/I2_bar;
                            %                                 C_bar = sqrt(Q2_bar/I2_bar-D_bar^2);
                            %                                 d_ampImb = sqrt(C_bar^2+D_bar^2)-1;
                            %                                 phi = atan(D_bar/C_bar);
                            %                                 I_rawdata = I_rawdata-miux;
                            %                                 Q_rawdata =((Q_rawdata-miuy)/(1+d_ampImb)-I_rawdata*sin(phi))/cos(phi);
                            
                            signalcomplex = I_rawdata+1i*Q_rawdata;
                            
                            % Data decimation
                            samp_rate_dec = app.sampRate/app.dec;
                            DeciData = decimate(signalcomplex, app.dec);
                            Xtime = (1:length(DeciData))/samp_rate_dec;
                            
                            I_DeciData = real(DeciData);
                            Q_DeciData = imag(DeciData);
                            
                            if app.time_scope_rebuild
                                % Data stream
                                app.UIAxes_timeScope.cla;
                                Yaxis = NaN(2, length(Xtime));
                                app.hLine_time_scope = line(app.UIAxes_timeScope, Xtime, Yaxis, 'LineWidth', 3);
                                app.hLine_time_scope(1).Color = [1 1 0];
                                app.hLine_time_scope(2).Color = [0 1 0];
                                % Amp_max = max(max(I_DeciData), max(Q_DeciData));
                                axis(app.UIAxes_timeScope,[0 max(Xtime) -inf inf]);
                                xlabel(app.UIAxes_timeScope, 'Time(s)');
                                ylabel(app.UIAxes_timeScope, 'Amplitude');
                                legend(app.UIAxes_timeScope, {'I-Channel', 'Q-Channel'},'FontSize', 12, 'TextColor', 'k',...
                                    'Color',[0.65,0.65,0.65]); % Create the legend
                                set(app.UIAxes_timeScope, 'XGrid','off', 'YGrid', 'on', 'GridColor', [0.23 0.44 0.34], 'GridLineStyle', '--', 'GridAlpha', 0.8)
                                drawnow
                                
                                app.time_scope_rebuild = 0;
                            else
                                app.hLine_time_scope(1).YData = I_DeciData;
                                app.hLine_time_scope(2).YData = Q_DeciData;
                                drawnow
                            end
                        catch
                            
                        end
                end
                
                switch app.TabGroup_2.SelectedTab.Title
                    case 'Velocity Scope'
                        try
                            df_dopp = samp_rate_dec / app.Nfftv;
                            Doppler_psd = 10*log10((abs(fftshift(fft(DeciData,app.Nfftv))).^2 ...
                                /df_dopp)+eps);
                            [Doppler_psd_max, Doppler_psd_max_index] = max(Doppler_psd);
                            
                            vel_axis = linspace(-app.velMax, app.velMax, app.Nfftv);
                            vel_max = vel_axis(Doppler_psd_max_index);
                            
                            if app.velocity_scope_rebuild
                                app.UIAxes_velocityScope.cla;
                                app.hLine_velocity_scope = line( app.UIAxes_velocityScope,vel_axis, Doppler_psd, 'LineWidth', 3);
                                app.hLine_velocity_scope.Color = [0 1 0];
                                hold(app.UIAxes_velocityScope, "on")
                                axis(app.UIAxes_velocityScope, [-app.velMax, app.velMax, 0, inf]);
                                xlabel(app.UIAxes_velocityScope,'Velocity (m/s)');
                                ylabel(app.UIAxes_velocityScope,'Intensity (dB)');
                                app.hLineV_velocity_scope = line(app.UIAxes_velocityScope, [vel_max vel_max],[0 Doppler_psd_max], 'LineStyle', '--', 'Color', [1 1 0], 'LineWidth', 3);
                                label = sprintf('Detected Velocity: %4.2f m/s ', vel_max);
                                app.hText_velocity_scope = text(app.UIAxes_velocityScope, vel_max+1, Doppler_psd_max-1, label, 'Color', [1 1 0]);
                                grid(app.UIAxes_velocityScope, 'on');
                                set(app.UIAxes_velocityScope, 'GridColor', [0.23 0.44 0.34], 'GridLineStyle', '--', 'GridAlpha', 0.8)
                                drawnow
                                
                                app.velocity_scope_rebuild = 0;
                            else
                                app.hLine_velocity_scope.YData = Doppler_psd;
                                app.hLineV_velocity_scope.XData = [vel_max, vel_max];
                                app.hLineV_velocity_scope.YData = [0 Doppler_psd_max];
                                label = sprintf('Detected Range: %4.2f m/s ', vel_max);
                                app.hText_velocity_scope.String = label;
                                app.hText_velocity_scope.Position = [vel_max+1, Doppler_psd_max-1];
                                axis(app.axes_velocity_scope, [-app.velMax, app.velMax, 0, max(Doppler_psd_max,1)]);
                                drawnow
                            end
                        catch
                            
                        end
                        
                    case 'Velocity Waterfall'
                        try
                            df_dopp = samp_rate_dec / app.Nfftv;
                            Doppler_psd = 10*log10((abs(fftshift(fft(DeciData,app.Nfftv))).^2 ...
                                /df_dopp)+eps);
                            
                            vel_axis = linspace(-app.velMax,app.velMax,app.Nfftv);
                            
                            if app.Velocity_WF_flag
                                m = app.HistorysizeframeSpinner.Value;
                                Velocity_WF = zeros(m, app.Nfftv);
                                app.Velocity_WF_flag = 0;
                            end
                            try
                                Velocity_WF(2:m,:) = Velocity_WF(1:m-1,:);
                                Velocity_WF(1,:) = Doppler_psd;
                            catch
                                app.Velocity_WF_flag = 1;
                                
                            end
                            
                            if app.velocity_waterfall_rebuild
                                app.UIAxes_velocityWaterfall.cla;
                                app.hVelocity_WF = imagesc(app.UIAxes_velocityWaterfall, vel_axis, (0:m-1), Velocity_WF);
                                axis(app.UIAxes_velocityWaterfall, [-app.velMax app.velMax 0 m-1]);
                                % dynamic range
                                clim_max = app.DRMaxKnob.Value;
                                clim_min = app.DRMinKnob.Value;
                                set(app.UIAxes_velocityWaterfall, 'CLim', [clim_min,clim_max]);
                                shading(app.UIAxes_velocityWaterfall, 'interp');
                                xlabel(app.UIAxes_velocityWaterfall,'Velocity (m/s)');
                                ylabel(app.UIAxes_velocityWaterfall,'Frame');
                                legend(app.UIAxes_velocityWaterfall,'off')
                                drawnow
                                
                                app.velocity_waterfall_rebuild = 0;
                            else
                                app.hVelocity_WF.CData = Velocity_WF;
                                drawnow
                            end
                            
                            if app.DRAutoCheckBox.Value
                                Velocity_WF(isinf(Velocity_WF)) = 0;
                                AutoDRMaxVal = max(max(Velocity_WF));
                                AutoDRMaxVal = max(AutoDRMaxVal, 88);
                                
                                [row,col] = size(Velocity_WF);
                                AutoDRMinVal = sum(sum(Velocity_WF))/(row*col);
                                AutoDRMinVal = max(AutoDRMinVal, 40);
                                
                                app.DRMaxKnob.Value = AutoDRMaxVal;
                                app.DRMinKnob.Value = AutoDRMinVal;
                                % clim = get(gca,'CLim');
                                set(app.UIAxes_velocityWaterfall, 'CLim', [AutoDRMinVal, AutoDRMaxVal]);
                            end
                        catch
                            
                        end
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('./'));
            
            % Modulation options
            app.modOpt.FMCW_sawtooth = uint16(0);
            app.modOpt.FMCW_triangle = uint16(1);
            app.modOpt.FSK = uint16(2);
            app.modOpt.CW = uint16(3);
            
            % Sweeptime options
            app.stOpt.us125 = uint16(0);
            app.stOpt.us250 = uint16(1);
            app.stOpt.us500 = uint16(2);
            app.stOpt.us1000 = uint16(3);
            app.stOpt.us2000 = uint16(4);
            app.stOpt.us4000 = uint16(5);
            app.stOpt.us8000 = uint16(6);
            
            % Samples/sweep options
            app.sampOpt.base = uint16(0);
            app.sampOpt.half = uint16(1);
            app.sampOpt.quarter = uint16(2);
            app.sampOpt.oneEighth = uint16(3);
            
            % Tx channel options
            app.txOpt.ch1 = uint16(bin2dec('0001'));
            app.txOpt.ch2 = uint16(bin2dec('0010'));
            app.txOpt.ch12 = uint16(bin2dec('0011'));
            
            % Rx channel options
            app.rxOpt.ch1 = uint16(bin2dec('0001'));
            app.rxOpt.ch2 = uint16(bin2dec('0010'));
            app.rxOpt.ch3 = uint16(bin2dec('0100'));
            app.rxOpt.ch4 = uint16(bin2dec('1000'));
            app.rxOpt.ch12 = uint16(bin2dec('0011'));
            app.rxOpt.ch34 = uint16(bin2dec('1100'));
            app.rxOpt.ch1234 = uint16(bin2dec('1111'));
            
            % Command init
            app.cmd_getInfo = uint16(zeros(app.cmd_len, 1));
            app.cmd_getInfo(1:app.cmd_rep) = app.getInfo_h;
            
            % GUI element enable
            app.StreamingSwitch.Enable = 'Off';
            app.control_element_enable('off');
            
        end

        % Button pushed function: ModelDetectionButton
        function ModelDetectionButtonPushed(app, event)
            try
                [~,~,endian] = computer;
                sdradar_init;
                sdradar_send_data(app.cmd_getInfo);
                kit_info = sdradar_get_data(app.USB_BUFFER_LEFTOVER+app.USB_BUFFER_BLOCK_SIZE);
                header_ind = find(kit_info == uint16(hex2dec('FA07')));
                if isempty(header_ind)
                    % RF board serial number extraction
                    header_ind = find(kit_info == uint16(hex2dec('FA0D')));
                    % tmp = dec2hex(kit_info(header_ind+8),4);
                    app.model_c = num2str(kit_info(header_ind+8),'%02u');
                    tmp = dec2hex((kit_info(header_ind+9)));
                    nTx_c = tmp(1);
                    app.nTx_max = str2double(nTx_c);
                    nRx_c = tmp(2);
                    app.nRx_max = str2double(nRx_c);
                    series_c = native2unicode(hex2dec(tmp(3:4)), 'UTF-8');
                    
                    if endian == 'L'
                        tmp = typecast(kit_info(header_ind+10),'uint8');
                        year = num2str(tmp(1), '%02u');
                        month = num2str(tmp(2), '%02u');
                        
                        tmp = typecast(kit_info(header_ind+11),'uint8');
                        brdIndLow = num2str(tmp(1), '%02u');
                        brdIndHi = num2str(tmp(2), '%02u');
                    else
                        tmp = typecast(kit_info(header_ind+10),'uint8');
                        month = num2str(tmp(1), '%02u');
                        year = num2str(tmp(2), '%02u');
                        
                        tmp = typecast(kit_info(header_ind+11),'uint8');
                        brdIndLow = num2str(tmp(2), '%02u');
                        brdIndHi = num2str(tmp(1), '%02u');
                    end
                    
                    rf_sn = strcat("RF", app.model_c, nTx_c, nRx_c, series_c,...
                        month, year, brdIndHi, brdIndLow);
                    
                    if series_c == 'A'
                        if app.nRx_max > 1
                            if nTx_c == '2'
                                app.model_s = "SDR-KIT 2400T2R4";
                            else
                                app.model_s = strcat("SDR-KIT ", app.model_c,"AD",nRx_c);
                            end
                        else
                            app.model_s = strcat("SDR-KIT ", app.model_c,"AD");
                        end
                    elseif series_c == 'B'
                        app.model_s = strcat("SDR-KITdd ", app.model_c,"B");
                    end
                    app.RFSNEditField.Value = rf_sn;
                    app.ModelEditField.Value = app.model_s;
                    
                    % PM board serial number extraction
                    tmp = dec2hex(kit_info(header_ind+4),4);
                    month = hex2dec(tmp(1:2));
                    year = hex2dec(tmp(3:4));
                    tmp = dec2hex(kit_info(header_ind+5),4);
                    brdIndHi = hex2dec(tmp(1:2));
                    brdIndLow = hex2dec(tmp(3:4));
                    pm_sn = strcat("PM",dec2hex(kit_info(header_ind+3),4), ...
                        num2str(month,'%02u'),num2str(year,'%02u'),num2str(brdIndHi,'%02u'),num2str(brdIndLow,'%02u'));
                    app.PMSNEditField.Value = pm_sn;
                    
                    app.MessageEditField.Value = "SDRadar connected";
                    app.MessageLamp.Color = 'g';
                else
                    % PM board serial number extraction
                    tmp = dec2hex(kit_info(header_ind+4),4);
                    month = hex2dec(tmp(1:2));
                    year = hex2dec(tmp(3:4));
                    tmp = dec2hex(kit_info(header_ind+5),4);
                    brdIndHi = hex2dec(tmp(1:2));
                    brdIndLow = hex2dec(tmp(3:4));
                    pm_sn = strcat("PM",dec2hex(kit_info(header_ind+3),4), ...
                        num2str(month,'%02u'),num2str(year,'%02u'),num2str(brdIndHi,'%02u'),num2str(brdIndLow,'%02u'));
                    app.PMSNEditField.Value = pm_sn;
                    
                    app.MessageEditField.Value = "No RF board found";
                    app.MessageLamp.Color = 'r';
                    return
                end
                
                % GUI element enable
                app.StreamingSwitch.Enable = 'On';
                app.control_element_enable('on')
                
                switch app.model_s
                    case "SDR-KIT 2400AD2"
                        % Default radar parameters
                        app.cmd_setMod = uint16(zeros(app.cmd_len, 1));
                        app.cmd_setMod(1:app.cmd_rep) = app.setMod_h + app.modOpt.FMCW_sawtooth;
                        
                        app.cmd_setSt = uint16(zeros(app.cmd_len, 1));
                        app.cmd_setSt(1:app.cmd_rep) = app.setSt_h + app.stOpt.us1000;
                        app.sTime = 1e-3;
                        
                        app.cmd_setSamp = uint16(zeros(app.cmd_len, 1));
                        app.cmd_setSamp(1:app.cmd_rep) = app.setSamp_h + app.sampOpt.oneEighth;
                        
                        app.nTx = 1; % 1 active transmitter
                        app.cmd_setTx = uint16(zeros(app.cmd_len, 1));
                        app.cmd_setTx(1:app.cmd_rep) = app.setTx_h + app.txOpt.ch1;
                        
                        app.nRx = 2; % 2 active receivers by default
                        app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                        app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch12;
                        
                        % Initial parameter configuration
                        sdradar_send_data(app.cmd_setMod);
                        sdradar_send_data(app.cmd_setSt);
                        sdradar_send_data(app.cmd_setSamp);
                        sdradar_send_data(app.cmd_setTx);
                        sdradar_send_data(app.cmd_setRx);
                        
                        app.fStart = 24e9;
                        app.fStop = 24.25e9;
                        app.PLL_register_setup;
                        
                        app.bw_max_GHz = 2;
                        app.nSamp_max = app.max_data_rate*app.sTime/app.nRx;
                        
                        % GUI element update
                        app.SamplesPerSweepOptionUpdate;
                        app.RampstartGHzSlider.Limits = [24 26];
                        app.RampstopGHzSlider.Limits = [24 26];
                        app.RampstartGHzSlider.Value = 24;
                        app.RampstopGHzSlider.Value = 24.25;
                        app.RampstartGHzEditField.Value = 24;
                        app.RampstopGHzEditField.Value = 24.25;
                        app.Tx1CheckBox.Value = 1;
                        app.Tx2CheckBox.Value = 0;
                        app.Rx1CheckBox.Value = 1;
                        app.Rx2CheckBox.Value = 1;
                        app.Rx3CheckBox.Value = 0;
                        app.Rx4CheckBox.Value = 0;
                        %                         app.Tx1CheckBox.Enable = 'off';
                        %                         app.Tx2CheckBox.Enable = 'off';
                        %                         app.Rx1CheckBox.Enable = 'on';
                        %                         app.Rx2CheckBox.Enable = 'on';
                        %                         app.Rx3CheckBox.Enable = 'off';
                        %                         app.Rx4CheckBox.Enable = 'off';
                        
                    case "SDR-KIT 2400T2R4"
                        % Default radar parameters
                        app.cmd_setMod = uint16(zeros(app.cmd_len, 1));
                        app.cmd_setMod(1:app.cmd_rep) = app.setMod_h + app.modOpt.FMCW_sawtooth;
                        
                        app.cmd_setSt = uint16(zeros(app.cmd_len, 1));
                        app.cmd_setSt(1:app.cmd_rep) = app.setSt_h + app.stOpt.us1000;
                        app.sTime = 1e-3;
                        
                        app.cmd_setSamp = uint16(zeros(app.cmd_len, 1));
                        app.cmd_setSamp(1:app.cmd_rep) = app.setSamp_h + app.sampOpt.oneEighth;
                        
                        app.nTx = 2; % 2 active transmitter
                        app.cmd_setTx = uint16(zeros(app.cmd_len, 1));
                        app.cmd_setTx(1:app.cmd_rep) = app.setTx_h + app.txOpt.ch12;
                        
                        app.nRx = 4; % 4 active receivers by default
                        app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                        app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch1234;
                        
                        % Initial parameter configuration
                        sdradar_send_data(app.cmd_setMod);
                        sdradar_send_data(app.cmd_setSt);
                        sdradar_send_data(app.cmd_setSamp);
                        sdradar_send_data(app.cmd_setTx);
                        sdradar_send_data(app.cmd_setRx);
                        
                        app.fStart = 24e9;
                        app.fStop = 24.25e9;
                        app.PLL_register_setup;
                        
                        app.bw_max_GHz = 2;
                        app.nSamp_max = app.max_data_rate*app.sTime/app.nRx;
                        
                        % GUI element update
                        app.SamplesPerSweepOptionUpdate;
                        app.RampstartGHzSlider.Limits = [24 26];
                        app.RampstopGHzSlider.Limits = [24 26];
                        app.RampstartGHzSlider.Value = 24;
                        app.RampstopGHzSlider.Value = 24.25;
                        app.RampstartGHzEditField.Value = 24;
                        app.RampstopGHzEditField.Value = 24.25;
                        app.Tx1CheckBox.Value = 1;
                        app.Tx2CheckBox.Value = 1;
                        app.Rx1CheckBox.Value = 1;
                        app.Rx2CheckBox.Value = 1;
                        app.Rx3CheckBox.Value = 1;
                        app.Rx4CheckBox.Value = 1;
                        % app.Tx1CheckBox.Enable = 'on';
                        % app.Tx2CheckBox.Enable = 'on';
                        % app.Rx1CheckBox.Enable = 'on';
                        % app.Rx2CheckBox.Enable = 'on';
                        % app.Rx3CheckBox.Enable = 'on';
                        % app.Rx4CheckBox.Enable = 'on';
                end
                
            catch % ME
                % GUI element enable
                app.StreamingSwitch.Enable = 'Off';
                app.control_element_enable('off')
                
                app.RFSNEditField.Value = "";
                app.PMSNEditField.Value = "";
                app.ModelEditField.Value = "";
                app.MessageEditField.Value = "SDRadar not connected";
                app.MessageLamp.Color = 'r';
                return;
            end
        end

        % Value changed function: RampstartGHzSlider
        function RampstartGHzSliderValueChanged(app, event)
            value = app.RampstartGHzSlider.Value;
            value = floor(value*1e3)/1e3;
            
            if value >= app.RampstopGHzSlider.Value
                app.RampstartGHzSlider.Value = app.RampstopGHzSlider.Value;
                
            elseif value < app.RampstopGHzSlider.Value
                % check if the bandwidth is larger than the maximum
                if app.RampstopGHzSlider.Value - value <= app.bw_max_GHz
                    app.RampstartGHzSlider.Value = value;
                else
                    app.RampstartGHzSlider.Value = app.RampstopGHzSlider.Value - app.bw_max_GHz;
                end
            end
            
            app.bw_update;
        end

        % Value changed function: RampstopGHzSlider
        function RampstopGHzSliderValueChanged(app, event)
            value = app.RampstopGHzSlider.Value;
            value = floor(value*1e3)/1e3;
            
            if value <= app.RampstartGHzSlider.Value
                app.RampstopGHzSlider.Value = app.RampstartGHzSlider.Value;
                
            elseif value > app.RampstartGHzSlider.Value
                % check if the bandwidth is larger than the maximum
                if value - app.RampstartGHzSlider.Value <= app.bw_max_GHz
                    app.RampstopGHzSlider.Value = value;
                else
                    app.RampstopGHzSlider.Value = app.RampstartGHzSlider.Value + app.bw_max_GHz;
                end
            end
            
            app.bw_update;
        end

        % Value changing function: RampstartGHzSlider
        function RampstartGHzSliderValueChanging(app, event)
            changingValue = event.Value;
            app.RampstartGHzEditField.Value = changingValue;
        end

        % Value changing function: RampstopGHzSlider
        function RampstopGHzSliderValueChanging(app, event)
            changingValue = event.Value;
            app.RampstopGHzEditField.Value = changingValue;
        end

        % Value changed function: SweeptimeusDropDown
        function SweeptimeusDropDownValueChanged(app, event)
            value = app.SweeptimeusDropDown.Value;
            switch value
                case '125'
                    app.cmd_setSt(1:app.cmd_rep) = app.setSt_h + app.stOpt.us125;
                    app.sTime = 125e-6;
                    
                    switch app.model_c
                        case '0240'
                            
                        case '0580'
                            
                        case '0980'
                            
                        case '2400'
                            app.bw_max_GHz = 0.25;
                            app.RampstartGHzSlider.Value = 24;
                            app.RampstopGHzSlider.Value = 24.25;
                            app.bw_update;
                    end
                    
                case '250'
                    app.cmd_setSt(1:app.cmd_rep) = app.setSt_h + app.stOpt.us250;
                    app.sTime = 250e-6;
                    
                    switch app.model_c
                        case '0240'
                            
                        case '0580'
                            
                        case '0980'
                            
                        case '2400'
                            app.bw_max_GHz = 0.25;
                            app.RampstartGHzSlider.Value = 24;
                            app.RampstopGHzSlider.Value = 24.25;
                            app.bw_update;
                    end
                    
                case '500'
                    app.cmd_setSt(1:app.cmd_rep) = app.setSt_h + app.stOpt.us500;
                    app.sTime = 500e-6;
                    
                    switch app.model_c
                        case '0240'
                            
                        case '0580'
                            
                        case '0980'
                            
                        case '2400'
                            app.bw_max_GHz = 1;
                            app.RampstartGHzSlider.Value = 24;
                            app.RampstopGHzSlider.Value = 24.25;
                            app.bw_update;
                    end
                    
                case '1000'
                    app.cmd_setSt(1:app.cmd_rep) = app.setSt_h + app.stOpt.us1000;
                    app.sTime = 1000e-6;
                    
                    switch app.model_c
                        case '0240'
                            
                        case '0580'
                            
                        case '0980'
                            
                        case '2400'
                            app.bw_max_GHz = 2;
                            app.RampstartGHzSlider.Value = 24;
                            app.RampstopGHzSlider.Value = 24.25;
                            app.bw_update;
                    end
                    
                case '2000'
                    app.cmd_setSt(1:app.cmd_rep) = app.setSt_h + app.stOpt.us2000;
                    app.sTime = 2000e-6;
                    
                    switch app.model_c
                        case '0240'
                            
                        case '0580'
                            
                        case '0980'
                            
                        case '2400'
                            app.bw_max_GHz = 2;
                            app.RampstartGHzSlider.Value = 24;
                            app.RampstopGHzSlider.Value = 24.25;
                            app.bw_update;
                    end
                    
                case '4000'
                    app.cmd_setSt(1:app.cmd_rep) = app.setSt_h + app.stOpt.us4000;
                    app.sTime = 4000e-6;
                    
                    switch app.model_c
                        case '0240'
                            
                        case '0580'
                            
                        case '0980'
                            
                        case '2400'
                            app.bw_max_GHz = 2;
                            app.RampstartGHzSlider.Value = 24;
                            app.RampstopGHzSlider.Value = 24.25;
                            app.bw_update;
                    end
                    
                case '8000'
                    app.cmd_setSt(1:app.cmd_rep) = app.setSt_h + app.stOpt.us8000;
                    app.sTime = 8000e-6;
                    
                    switch app.model_c
                        case '0240'
                            
                        case '0580'
                            
                        case '0980'
                            
                        case '2400'
                            app.bw_max_GHz = 2;
                            app.RampstartGHzSlider.Value = 24;
                            app.RampstopGHzSlider.Value = 24.25;
                            app.bw_update;
                    end
            end
            sdradar_send_data(app.cmd_setSt);
            app.nSamp_max = app.max_data_rate*app.sTime/app.nRx;
            app.SamplesPerSweepOptionUpdate;
            app.SamplesPerSweepDropDownValueChanged;
        end

        % Value changed function: SamplesPerSweepDropDown
        function SamplesPerSweepDropDownValueChanged(app, event)
            value = app.SamplesPerSweepDropDown.Value;
            app.nSamp = str2double(value);
            nSamp_dec_factor = app.nSamp_max/app.nSamp;
            switch nSamp_dec_factor
                case 1
                    app.cmd_setSamp(1:app.cmd_rep) = app.setSamp_h + app.sampOpt.base;
                case 2
                    app.cmd_setSamp(1:app.cmd_rep) = app.setSamp_h + app.sampOpt.half;
                case 4
                    app.cmd_setSamp(1:app.cmd_rep) = app.setSamp_h + app.sampOpt.quarter;
                case 8
                    app.cmd_setSamp(1:app.cmd_rep) = app.setSamp_h + app.sampOpt.oneEighth;
            end
            sdradar_send_data(app.cmd_setSamp);
            plot_rebuild(app,1);
            update_calculated_param(app);
        end

        % Value changed function: StreamingSwitch
        function StreamingSwitchValueChanged(app, event)
            %javaaddpath 'C:\Users\muhammadm\OneDrive - Universitetet i Agder\Muhammad Muaaz-OD\Real-time visualization-Intel-CSI-Tool\Radar\Matlab App\'
            %             pathToSpeech = fileparts(which('T4.py'));
            %             if count(py.sys.path,pathToSpeech) == 0
            %                 insert(py.sys.path,int32(0),pathToSpeech);
            %             end
            
            if(strcmp(app.StreamingSwitch.Value, 'Off'))
                app.StreamingOnLamp.Color = 'w';
                app.StreamingOffLamp.Color = 'r';
                app.SaveRealtimestreamCheckBox.Enable = 'off';
                
            else
                app.SaveRealtimestreamCheckBox.Enable = 'on';
                app.StreamingOnLamp.Color = 'g';
                app.StreamingOffLamp.Color = 'w';
                
                plot_rebuild(app, 1);
                update_calculated_param(app);
                app.PN = app.SweepstoProcessSpinner.Value;
                pause(0.005)
                
                asyncBuff= dsp.AsyncBuffer(app.nSamp*app.PN*20);
                
                while strcmp(app.StreamingSwitch.Value,'On')
                    tic;
                    app.SaveRealtimestreamCheckBox.Enable = 'on';
                    sdradar_send_data(uint16(zeros(app.cmd_len, 1)));
                    complex_data_from_radar = get_complex_data_CHx(app, app.PN); % complex baseband data
                    
                    %write(asyncBuff,complex_data_from_radar);
                    
                    %if (asyncBuff.NumUnreadSamples >= app.nSamp*app.PN*5)
                        %data_from_Buff = read(asyncBuff,app.nSamp*app.PN*5);
                        %data_from_Buff = read(asyncBuff,app.nSamp*app.PN*10, app.nSamp*app.PN*5);
                        
                        if (app.SaveRealtimestreamCheckBox.Value)
                            %saveData_2(app, data_from_Buff);
                            saveData_2(app, complex_data_from_radar);
                            
                        else
                            plotting(app, complex_data_from_radar);
                            %plotting(app, data_from_Buff);
                        end
                    %end
                    drawnow;
                    
                    if strcmp(app.StreamingSwitch.Value, 'Off')
                        app.StreamingOnLamp.Color = 'w';
                        app.StreamingOffLamp.Color = 'r';
                        app.SaveRealtimestreamCheckBox.Enable = 'off';
                        %clear asyncBuff;
                        break;
                    end
                    toc;
                end
                
            end
            
            
            %             if strcmp(app.StreamingSwitch.Value, 'Off')
            %                 app.StreamingOnLamp.Color = 'w';
            %                 app.StreamingOffLamp.Color = 'r';
            %                 app.SaveRealtimestreamCheckBox.Enable = 'off';
            %
            %             end
            %
            %             plot_rebuild(app, 1);
            %             update_calculated_param(app);
            %
            %
            %             while strcmp(app.StreamingSwitch.Value,'On')
            %                 tic;
            %                 app.SaveRealtimestreamCheckBox.Enable = 'on';
            %                 app.StreamingOnLamp.Color = 'g';
            %                 app.StreamingOffLamp.Color = 'w';
            %                 % requesting session
            %                 % need to send something to the kit before each data
            %                 if(strcmp(app.StreamingSwitch.Value,'On'))
            %                     sdradar_send_data(uint16(zeros(app.cmd_len, 1)));
            %                     complex_data = get_complex_data_CHx(app, app.PN); % complex baseband data
            %                 end
            %                 if (app.SaveRealtimestreamCheckBox.Value)
            %                    saveData_2(app, complex_data);
            %                 end
            %                 plotting(app, complex_data);
            %                 %toc;
            %                 %out_file_name = strcat(app.stream_save_dir,'\',string(datetime('now','Format','d-MMM-y_HH-mm-ss')), '.mat');
            %                 %py.T4.main(out_file_name, real(complex_data), imag(complex_data), py.int(app.nSamp), py.int(app.PN))
            %                 toc;
            %                 %                 if strcmp(app.ModulationDropDown.Value, 'FMCW Sawtooth')
            %                 %
            %                 %                     % Upper tab group selection
            %                 %                     switch app.TabGroup.SelectedTab.Title
            %                 %                         case 'Time Scope'
            %                 %                             try
            %                 %                                 datastream = complex_data(1:1+app.N_avg*app.nSamp-1);
            %                 %                                 % Time-Scope display
            %                 %                                 IData = real(datastream); % I data
            %                 %                                 QData = imag(datastream); % Q data
            %                 %                                 IMatrix = reshape(IData,app.nSamp,app.N_avg);
            %                 %                                 QMatrix = reshape(QData,app.nSamp,app.N_avg);
            %                 %                                 I_rawdata = mean(IMatrix,2);
            %                 %                                 Q_rawdata = mean(QMatrix,2);
            %                 %
            %                 %                                 if app.time_scope_rebuild
            %                 %                                     app.UIAxes_timeScope.cla;
            %                 %                                     Xaxis = 1:app.nSamp;
            %                 %                                     Yaxis = NaN(2, app.nSamp);
            %                 %                                     app.hLine_time_scope = line(app.UIAxes_timeScope, Xaxis, Yaxis, 'LineWidth', 3);
            %                 %                                     app.hLine_time_scope(1).Color = [0 1 0];
            %                 %                                     app.hLine_time_scope(2).Color = [1 1 0];
            %                 %                                     xlabel(app.UIAxes_timeScope,'Samples per sweep');
            %                 %                                     ylabel(app.UIAxes_timeScope,'Amplitude');
            %                 %                                     set(app.UIAxes_timeScope, 'XGrid','off','YGrid', 'on', 'GridColor', [0.23 0.44 0.34], 'GridLineStyle', '--', 'GridAlpha', 0.8)
            %                 %                                     set(app.UIAxes_timeScope,'Color','Black');
            %                 %                                     legend(app.UIAxes_timeScope, {'I-Channel', 'Q-Channel'},'FontSize', 12, 'TextColor', 'k',...
            %                 %                                         'Color',[0.65,0.65,0.65]); % Create the legend
            %                 %                                     axis(app.UIAxes_timeScope, [1 app.nSamp -inf inf]);
            %                 %                                     drawnow;
            %                 %
            %                 %                                     app.time_scope_rebuild = 0;
            %                 %                                 else
            %                 %                                     app.hLine_time_scope(1).YData = I_rawdata - mean(I_rawdata);
            %                 %                                     app.hLine_time_scope(2).YData = Q_rawdata - mean(Q_rawdata);
            %                 %                                     drawnow
            %                 %                                 end
            %                 %
            %                 %                             catch
            %                 %
            %                 %                                 continue;
            %                 %
            %                 %                             end
            %                 %
            %                 %                         case 'Spectrogram'
            %                 %                             tic;
            %                 %                             % Enable stream save switch
            %                 %                             app.SaveRealtimestreamCheckBox.Enable = 'on';
            %                 %
            %                 %                             % Case when user has selected the Spectrogram Tab
            %                 %
            %                 %                             try
            %                 %                                % Complex_data seems to be a one dimensional [Check it with device]
            %                 %                                data_matrix = reshape(complex_data, app.nSamp, app.PN);
            %                 %                                data_matrix = bsxfun(@minus, data_matrix, mean(data_matrix, 2)); % remove static objects
            %                 %
            %                 %                                data_matrix = data_matrix(1:4:size(data_matrix,1),:);
            %                 %
            %                 %                                %% Save complex stream data to the file
            %                 %
            %                 % %                                if (app.saveStream)
            %                 % %
            %                 % %                                    saveData(app, data_matrix);
            %                 % %
            %                 % %                                end
            %                 %                                % if PCA checkbox is selected.
            %                 %                                if(app.PCACheckBox.Value)
            %                 %
            %                 %                                    % Check dimensions. If num colums is
            %                 %                                    % less than num rows PCA can not be
            %                 %                                    % applied.
            %                 %                                    if(app.PN <= app.nSamp)
            %                 %                                        % PCA can not be applied just sum
            %                 %                                        % over all frequencies.
            %                 %                                        x = conj(sum(tmp, 1));
            %                 %                                    else
            %                 %                                        % Apply Prinicipal Component analysis
            %                 %                                        [coeff, score, ~, ~, explained, ~] = pca(data_matrix');
            %                 %
            %                 %                                        %Check how many components user has
            %                 %                                        %selected.
            %                 %                                        if(app.numPCA_components <= 1)
            %                 %
            %                 %                                            x = conj(score(:,1)');
            %                 %                                        else
            %                 %                                            % Note that transpose is applied
            %                 %                                            % after computing the sum.
            %                 %                                            x = conj(sum(score(:,1:app.numPCA_components),2)');
            %                 %                                        end
            %                 %                                    end
            %                 %
            %                 %                                else % if PCA checkbox is not selected.
            %                 %
            %                 %                                    x = conj(sum(data_matrix, 1));
            %                 %
            %                 %                                end
            %                 %
            %                 %                                if(app.HighPassFilterCheckBox.Value)
            %                 %
            %                 %                                    ns = size(x,2);
            %                 %                                    [b,a] = butter(3,0.05, 'high');
            %                 %                                    [h, f1] = freqz(b, a, ns);
            %                 %
            %                 %                                    x = filtfilt(b,a,x(1:ns));
            %                 %
            %                 %                                end
            %                 %
            %                 %                                Fs = 1/(app.nTx*app.sTime);
            %                 %                                [STFT,f,t] = spectrogram(x,kaiser(64,10),63,512,Fs,'centered','yaxis');
            %                 %                                Sxx = abs(STFT).^2;
            %                 %                                Sxx_norm = Sxx./sum(Sxx,1);
            %                 %
            %                 %                                % Now Add code to plot the spectrogram
            %                 %                                if app.spectrogram_scope_rebuild
            %                 %
            %                 %                                    app.UIAxes_spectrogramScope.cla;
            %                 %                                    colormap(app.UIAxes_spectrogramScope, 'hot');
            %                 %                                    shading(app.UIAxes_spectrogramScope, 'interp');
            %                 %                                    xlabel(app.UIAxes_spectrogramScope,'Time (s)');
            %                 %                                    ylabel(app.UIAxes_spectrogramScope,'Frequency (Hz)');
            %                 %                                    app.UIAxes_spectrogramScope.View(2);
            %                 %                                    axis(app.UIAxes_spectrogramScope, [t(1),t(end), -500, 500]);
            %                 %                                    Xaxis = t;
            %                 %                                    Yaxis = f;
            %                 %                                    Zaxis = Sxx_norm;
            %                 %                                    app.hSurf_spectrogram_scope = imagesc(app.UIAxes_spectrogramScope, Xaxis, Yaxis, Zaxis);
            %                 %                                    drawnow;
            %                 %
            %                 %                                    app.spectrogram_scope_rebuild = 0;
            %                 %                                else
            %                 %                                    app.hSurf_spectrogram_scope.CData = Sxx_norm;
            %                 %                                    drawnow;
            %                 %                                end
            %                 %
            %                 %                             toc;
            %                 %                             catch
            %                 %                                 continue;
            %                 %
            %                 %                             end
            %                 %
            %                 %                         case 'Mean Doppler Shift'
            %                 %
            %                 %                             % Case when user has selected the mean Doppler
            %                 %                             % Shift Tab
            %                 %
            %                 %                             try
            %                 %                                data_matrix = reshape(complex_data, app.nSamp, app.PN);
            %                 %                                data_matrix = bsxfun(@minus, data_matrix, mean(data_matrix, 2)); % remove static objects
            %                 %
            %                 %
            %                 %                                % if PCA checkbox is selected.
            %                 %                                if(app.PCACheckBox.Value)
            %                 %
            %                 %                                    % Check dimensions. If num colums is
            %                 %                                    % less than num rows PCA can not be
            %                 %                                    % applied.
            %                 %                                    if(app.PN <= app.nSamp)
            %                 %                                        % PCA can not be applied just sum
            %                 %                                        % over all frequencies.
            %                 %                                        x = conj(sum(tmp, 1));
            %                 %                                    else
            %                 %                                        % Apply Prinicipal Component analysis
            %                 %                                        [coeff, score, ~, ~, explained, ~] = pca(data_matrix');
            %                 %
            %                 %                                        %Check how many components user has
            %                 %                                        %selected.
            %                 %                                        if(app.numPCA_components <= 1)
            %                 %
            %                 %                                            x = conj(score(:,1)');
            %                 %
            %                 %                                        else
            %                 %                                            % Note that transpose is applied
            %                 %                                            % after computing the sum.
            %                 %                                            x = conj(sum(score(:,1:app.numPCA_components),2)');
            %                 %
            %                 %                                        end
            %                 %                                    end
            %                 %
            %                 %                                else % if PCA checkbox is not selected.
            %                 %
            %                 %                                    x = conj(sum(data_matrix, 1));
            %                 %
            %                 %                                end
            %                 %
            %                 %                                if(app.HighPassFilterCheckBox.Value)
            %                 %                                    ns = size(x,2);
            %                 %                                    [b,a] = butter(3,0.05, 'high');
            %                 %                                    [h, f1] = freqz(b, a, ns);
            %                 %                                    x = filtfilt(b,a,x(1:ns));
            %                 %
            %                 %                                end
            %                 %
            %                 %                                Fs = 1/(app.nTx*app.sTime);
            %                 %                                [STFT,f,t] = spectrogram(x,kaiser(64,10),63,512,Fs,'centered','yaxis');
            %                 %                                Sxx = abs(STFT).^2;
            %                 %                                Sxx_norm = Sxx./sum(Sxx,1);
            %                 %                                mean_Exact_Radar = sum(f.*Sxx_norm,1)./sum(Sxx_norm,1);
            %                 %
            %                 %                                if app.mean_doppler_shift_rebuild
            %                 %                                     app.UIAxes_meanDopplerShiftScope.cla;
            %                 %
            %                 %                                     Xaxis = t;
            %                 %                                     Yaxis = mean_Exact_Radar;
            %                 %                                     app.hLine_mean_doppler_shift_scope = line(app.UIAxes_meanDopplerShiftScope, Xaxis, Yaxis, 'LineWidth', 2);
            %                 %                                     app.hLine_mean_doppler_shift_scope.Color = [0 1 0];
            %                 %                                     xlabel(app.UIAxes_meanDopplerShiftScope,'Time (s)');
            %                 %                                     ylabel(app.UIAxes_meanDopplerShiftScope,'Frequency (Hz)');
            %                 %                                     set(app.UIAxes_meanDopplerShiftScope, 'XGrid','off','YGrid', 'on', 'GridColor', [0.23 0.44 0.34], 'GridLineStyle', '--', 'GridAlpha', 0.8);
            %                 %                                     set(app.UIAxes_meanDopplerShiftScope,'Color','Black');
            %                 %                                     axis(app.UIAxes_meanDopplerShiftScope, [t(1) t(end) -500 500]);
            %                 %                                     drawnow;
            %                 %
            %                 %                                app.mean_doppler_shift_rebuild = 0;
            %                 %                                else
            %                 %                                    app.hLine_mean_doppler_shift_scope.YData  = mean_Exact_Radar;
            %                 %
            %                 %                                end
            %                 %
            %                 %                             catch
            %                 %
            %                 %                                 continue;
            %                 %
            %                 %                             end
            %                 %                     end
            %                 %
            %                 %                     % Lower tab group selection
            %                 %                     switch app.TabGroup_2.SelectedTab.Title
            %                 % %                         case 'Range Scope'
            %                 % %                             try
            %                 % %                                 datastream = complex_data(1:1+app.N_avg*app.nSamp-1);
            %                 % %                                 IData = real(datastream); % I data
            %                 % %                                 IMatrix = reshape(IData,app.nSamp,app.N_avg);
            %                 % %                                 I_rawdata = mean(IMatrix,2);
            %                 % %
            %                 % %                                 if app.RangeNotchFilterCheckBox.Value % Range Notch Filter (A bandpass filter)
            %                 % %                                     notchfreq = app.RangeNotchSlider.Value+1;
            %                 % %                                     if notchfreq >= app.sampRate/2.5
            %                 % %                                         notchfreq = app.sampRate/2.5;
            %                 % %                                     end
            %                 % %                                     F1 = 2*notchfreq/app.sampRate;
            %                 % %                                     F2 = 2*app.sampRate/2.1/app.sampRate;
            %                 % %                                     F12 = [F1,F2];
            %                 % %                                     N = 8;
            %                 % %                                     [BF,AF] = butter(N,F12);
            %                 % %                                     I_rawdata = I_rawdata - mean(I_rawdata);
            %                 % %                                     sgn = filter(BF,AF,I_rawdata);
            %                 % %                                 else
            %                 % %                                     sgn = I_rawdata;
            %                 % %                                 end
            %                 % %
            %                 % %                                 Range profile on I Channel stream
            %                 % %                                 sgn = sgn .* hamming(app.nSamp);
            %                 % %                                 df = app.sampRate /  app.Nfftr; % frequency resolution
            %                 % %                                 fsignal_I = fft(sgn, app.Nfftr);
            %                 % %                                 fsignal_I = fsignal_I(1:app.Nfftr/2+1);
            %                 % %                                 Range_psd = (1/df).*abs(fsignal_I).^2;
            %                 % %                                 Range_psd(2:end-1) = 2*Range_psd(2:end-1);
            %                 % %                                 Range_psd = 10*log10(Range_psd + eps); % power spectral density
            %                 % %                                 [Range_psd_max, rng_psd_max_index] = max(Range_psd);
            %                 % %                                 [Range_psd_min, ~] = min(Range_psd);
            %                 % %
            %                 % %                                 rng_axis = linspace(0, app.sampRate/2, app.Nfftr/2+1)*3e8* ...
            %                 % %                                     app.sTime/(2*app.bw);
            %                 % %                                 rng_max = rng_axis(rng_psd_max_index);
            %                 % %
            %                 % %                                 if app.range_scope_rebuild
            %                 % %                                     app.UIAxes_rangeScope.cla;
            %                 % %                                     app.hLine_range_scope = line(app.UIAxes_rangeScope, rng_axis, Range_psd, 'LineWidth', 3);
            %                 % %                                     app.hLine_range_scope.Color = [0 1 0];
            %                 % %                                     axis(app.UIAxes_rangeScope, [0, app.rngMax, 0, max(Range_psd_max, 20)]);
            %                 % %                                     xlabel(app.UIAxes_rangeScope,'Range (meters)');
            %                 % %                                     ylabel(app.UIAxes_rangeScope,'Power/frequency (dB/Hz)');
            %                 % %                                     hold(app.UIAxes_rangeScope,"on");
            %                 % %                                     set(app.UIAxes_rangeScope, 'XGrid','on','YGrid','on','GridColor', [0.23 0.44 0.34], 'GridLineStyle', '--', 'GridAlpha', 0.8, 'Color', 'Black');
            %                 % %                                     app.hLineV_range_scope = line(app.UIAxes_rangeScope, [rng_max rng_max],[-1000 1000], 'LineStyle', '--', 'Color', [1 1 0], 'LineWidth', 3);
            %                 % %                                     label = sprintf('Detected Range: %4.2f m ', rng_max);
            %                 % %                                     app.hText_range_scope = text(app.UIAxes_rangeScope, rng_max+1, Range_psd_max-1, label, 'Color', [1 1 0]);
            %                 % %                                     drawnow
            %                 % %
            %                 % %                                     app.range_scope_rebuild = 0;
            %                 % %                                 else
            %                 % %                                     app.hLine_range_scope.YData = Range_psd;
            %                 % %                                     app.hLineV_range_scope.XData = [rng_max, rng_max];
            %                 % %                                     app.hLineV_range_scope.YData = [Range_psd_min Range_psd_max];
            %                 % %                                     label = sprintf('Detected Range: %4.2f m ', rng_max);
            %                 % %                                     app.hText_range_scope.String = label;
            %                 % %                                     app.hText_range_scope.Position = [rng_max+1, Range_psd_max-1];
            %                 % %                                     axis(app.UIAxes_rangeScope, [0, app.rngMax, 0, max(Range_psd_max,1)]);
            %                 % %                                     drawnow
            %                 % %                                 end
            %                 % %                             catch
            %                 % %                                 continue
            %                 % %                             end
            %                 %
            %                 %                         case 'Range Doppler'
            %                 %                             try
            %                 %                                 df_rng = app.sampRate /  app.Nfftr; % frequency resolution in range
            %                 %                                 df_dopp = app.prf / app.Nfftv; % frequency resolution in Doppler
            %                 %                                 data_matrix = reshape(complex_data, app.nSamp, app.PN);
            %                 %                                 data_matrix = bsxfun(@minus, data_matrix, mean(data_matrix, 2)); % remove static objects
            %                 %                                 % Discard down-chirp samples
            %                 %                                 % Sweep_N = getappdata(handles.SDRadar, 'Sweep_N');
            %                 %                                 % rcut = round(Sweep_N/4/dec);
            %                 %                                 % % skip a loop if:
            %                 %                                 % if rcut > handles.NTS
            %                 %                                 %     continue
            %                 %                                 % end
            %                 %                                 % data_matrix = data_matrix(1:rcut, :);
            %                 %
            %                 %                                 % Filtering in range
            %                 %                                 if app.RangeNotchFilterCheckBox.Value
            %                 %                                     notchfreq = app.RangeNotchSlider.Value+1;
            %                 %                                     if notchfreq >= app.sampRate/2.5
            %                 %                                         notchfreq = app.sampRate/2.5;
            %                 %                                     end
            %                 %                                     F1 = 2*notchfreq/app.sampRate;
            %                 %                                     F2 = 2*app.sampRate/2.1/app.sampRate;
            %                 %                                     F12 = [F1,F2];
            %                 %                                     N = 8;
            %                 %                                     [BF,AF] = butter(N,F12);
            %                 %                                     data_matrix = filter(BF,AF,data_matrix,[],1);
            %                 %                                 end
            %                 %
            %                 %                                 % Range profiles
            %                 %                                 Rng_dft = fft(data_matrix, app.Nfftr, 1);
            %                 %                                 Rng_dft = Rng_dft(1:app.Nfftr/2, :);
            %                 %                                 % Rng_dft = bsxfun(@minus, Rng_dft, mean(Rng_dft, 2)); % remove stationary objects
            %                 %
            %                 %                                 % Filtering in Doppler
            %                 %                                 if app.DopplerNotchFilterCheckBox.Value
            %                 %                                     notch_vr = app.DopplerNotchSlider.Value+0.01;
            %                 %                                     notch_doppler = 2*notch_vr/(3e8/app.fc);
            %                 %                                     stopfreq = notch_doppler;
            %                 %                                     if stopfreq > app.prf/3
            %                 %                                         stopfreq = app.prf/3.1;
            %                 %                                     end
            %                 %                                     passfreq = 1.5*stopfreq;
            %                 %                                     ws = stopfreq/(app.prf/2);
            %                 %                                     wp = passfreq/(app.prf/2);
            %                 %                                     Rp = 2;
            %                 %                                     Rs = 30;
            %                 %                                     [N,Wn] = buttord(wp,ws,Rp,Rs);
            %                 %                                     [num,den] = butter(N,Wn,'high'); % Highpass filter
            %                 %                                     Rng_dft = filter(num,den,Rng_dft,[],2); % Doppler Notch filter
            %                 %                                 end
            %                 %
            %                 %                                 RngDopp_psd = abs(fftshift(fft(Rng_dft,app.Nfftv,2),2)).^2/(df_dopp*df_rng);
            %                 %
            %                 %                                 if app.range_doppler_rebuild
            %                 %                                     dopp_axis = linspace(-app.prf/2,app.prf/2,app.Nfftv)*3e8/app.fc/2;
            %                 %                                     rng_axis = linspace(0, app.sampRate/2, app.Nfftr/2+1)*3e8* ...
            %                 %                                         app.sTime/(2*app.bw);
            %                 %                                     app.UIAxes_rangeDoppler.cla;
            %                 %                                     app.hRngDopp = imagesc(app.UIAxes_rangeDoppler, dopp_axis, rng_axis, 10*log10(RngDopp_psd));
            %                 %                                     % axis xy
            %                 %                                     % clim = get(app.UIAxes_rangeDoppler,'CLim');
            %                 %                                     clim_max = app.DRMaxKnob.Value;
            %                 %                                     clim_min = app.DRMinKnob.Value;
            %                 %                                     set(app.UIAxes_rangeDoppler, 'CLim', [clim_min, clim_max]);
            %                 %                                     axis(app.UIAxes_rangeDoppler, [-app.velMax, app.velMax, 0, app.rngMax])
            %                 %                                     xlabel(app.UIAxes_rangeDoppler, 'Velocity (m/s)')
            %                 %                                     ylabel(app.UIAxes_rangeDoppler, 'Range (m)')
            %                 %                                     legend(app.UIAxes_rangeDoppler, 'off')
            %                 %                                     shading(app.UIAxes_rangeDoppler, 'interp');
            %                 %                                     drawnow
            %                 %
            %                 %                                     app.range_doppler_rebuild = 0;
            %                 %                                 else
            %                 %                                     app.hRngDopp.CData =10*log10(RngDopp_psd);
            %                 %                                     drawnow
            %                 %                                 end
            %                 %
            %                 %                                 if app.DRAutoCheckBox.Value
            %                 %                                     RngDopp_psd_dB = 10*log10(RngDopp_psd);
            %                 %                                     RngDopp_psd_dB(isinf(RngDopp_psd_dB)) = 0;
            %                 %                                     AutoDRMaxVal = max(max(RngDopp_psd_dB));
            %                 %                                     AutoDRMaxVal = max(AutoDRMaxVal, 88);
            %                 %
            %                 %                                     [row,col] = size(RngDopp_psd_dB);
            %                 %                                     AutoDRMinVal = sum(sum(RngDopp_psd_dB))/(row*col);
            %                 %                                     AutoDRMinVal = max(AutoDRMinVal, 40);
            %                 %
            %                 %                                     app.DRMaxKnob.Value = AutoDRMaxVal;
            %                 %                                     app.DRMinKnob.Value = AutoDRMinVal;
            %                 %                                     % clim = get(gca,'CLim');
            %                 %                                     set(app.UIAxes_rangeDoppler, 'CLim', [AutoDRMinVal, AutoDRMaxVal]);
            %                 %                                 end
            %                 %                             catch
            %                 %                                 continue
            %                 %                             end
            %                 %
            %                 %                         case 'Range Waterfall'
            %                 %                             try
            %                 %                                 datastream = complex_data(1:1+app.N_avg*app.nSamp-1);
            %                 %                                 IData = real(datastream); % I data
            %                 %                                 IMatrix = reshape(IData,app.nSamp,app.N_avg);
            %                 %                                 I_rawdata = mean(IMatrix,2);
            %                 %
            %                 %                                 % Filtering
            %                 %                                 if app.RangeNotchFilterCheckBox.Value % Range Notch Filter (A bandpass filter)
            %                 %                                     notchfreq = app.RangeNotchSlider.Value+1;
            %                 %                                     if notchfreq >= app.sampRate/2.5
            %                 %                                         notchfreq = app.sampRate/2.5;
            %                 %                                     end
            %                 %                                     F1 = 2*notchfreq/app.sampRate;
            %                 %                                     F2 = 2*app.sampRate/2.1/app.sampRate;
            %                 %                                     F12 = [F1,F2];
            %                 %                                     N = 8;
            %                 %                                     [BF,AF] = butter(N,F12);
            %                 %                                     I_rawdata = I_rawdata - mean(I_rawdata);
            %                 %                                     sgn = filter(BF,AF,I_rawdata);
            %                 %                                 end
            %                 %
            %                 %                                 % Range profile on I Channel stream
            %                 %                                 % sgn = sgn .* hamming(handles.NTS);
            %                 %                                 df = app.sampRate/app.Nfftr; % frequency resolution
            %                 %                                 fsignal_I = fft(sgn, app.Nfftr);
            %                 %                                 fsignal_I = fsignal_I(1:app.Nfftr/2+1);
            %                 %                                 Range_psd = (1/df).*abs(fsignal_I).^2;
            %                 %                                 Range_psd(2:end-1) = 2*Range_psd(2:end-1);
            %                 %                                 Range_psd = 10*log10(Range_psd + eps); % power spectral density
            %                 %
            %                 %                                 if app.Range_WF_flag
            %                 %                                     m = app.HistorysizeframeSpinner.Value;
            %                 %                                     Range_WF = zeros(m, app.Nfftr/2+1);
            %                 %                                     app.Range_WF_flag = 0;
            %                 %                                 end
            %                 %
            %                 %                                 try
            %                 %                                     Range_WF(2:m,:) = Range_WF(1:m-1,:);
            %                 %                                     Range_WF(1,:) = Range_psd;
            %                 %                                 catch
            %                 %                                     app.Range_WF_flag = 1;
            %                 %                                     continue
            %                 %                                 end
            %                 %
            %                 %                                 if app.range_waterfall_rebuild
            %                 %                                     rng_axis = linspace(0, app.sampRate/2, app.Nfftr/2+1)*3e8* ...
            %                 %                                         app.sTime/(2*app.bw);
            %                 %
            %                 %                                     app.UIAxes_rangeWaterfall.cla;
            %                 %                                     app.hRange_WF = imagesc(app.UIAxes_rangeWaterfall, rng_axis, (0:m-1), Range_WF);
            %                 %                                     axis(app.UIAxes_rangeWaterfall, [0 app.rngMax 0 m-1]);
            %                 %                                     % axis xy
            %                 %                                     % dynamic range
            %                 %                                     % clim = get(gca,'CLim');
            %                 %                                     clim_max = app.DRMaxKnob.Value;
            %                 %                                     clim_min = app.DRMinKnob.Value;
            %                 %                                     set(app.UIAxes_rangeWaterfall, 'CLim', [clim_min,clim_max]);
            %                 %                                     shading(app.UIAxes_rangeWaterfall, 'interp');
            %                 %                                     xlabel(app.UIAxes_rangeWaterfall,'Range (m)');
            %                 %                                     ylabel(app.UIAxes_rangeWaterfall,'Frame');
            %                 %                                     legend(app.UIAxes_rangeWaterfall,'off')
            %                 %                                     drawnow
            %                 %
            %                 %                                     app.range_waterfall_rebuild = 0;
            %                 %                                 else
            %                 %                                     app.hRange_WF.CData =  Range_WF;
            %                 %                                     drawnow
            %                 %                                 end
            %                 %
            %                 %                                 if app.DRAutoCheckBox.Value
            %                 %                                     Range_WF(isinf(Range_WF)) = 0;
            %                 %                                     AutoDRMaxVal = max(max(Range_WF));
            %                 %                                     AutoDRMaxVal = max(AutoDRMaxVal, 88);
            %                 %
            %                 %                                     [row,col] = size(Range_WF);
            %                 %                                     AutoDRMinVal = sum(sum(Range_WF))/(row*col);
            %                 %                                     AutoDRMinVal = max(AutoDRMinVal, 40);
            %                 %
            %                 %                                     app.DRMaxKnob.Value = AutoDRMaxVal;
            %                 %                                     app.DRMinKnob.Value = AutoDRMinVal;
            %                 %                                     % clim = get(gca,'CLim');
            %                 %                                     set(app.UIAxes_rangeWaterfall, 'CLim', [AutoDRMinVal, AutoDRMaxVal]);
            %                 %                                 end
            %                 %                             catch
            %                 %                                 continue
            %                 %                             end
            %                 %
            %                 %                     end
            %                 %                 elseif strcmp(app.ModulationDropDown.Value, 'CW')
            %                 %                     switch app.TabGroup.SelectedTab.Title
            %                 %                         case 'Time Scope'
            %                 %                             try
            %                 %                                 % The first 2 sweeps do not look good. Dump them
            %                 %                                 complex_data = complex_data(1+10*app.nSamp:end);
            %                 %                                 I_rawdata = real(complex_data) - mean(real(complex_data));
            %                 %                                 Q_rawdata = imag(complex_data) - mean(imag(complex_data));
            %                 %
            %                 %                                 % IQ imbalance correction
            %                 %                                 %                                 miux = mean(I_rawdata);
            %                 %                                 %                                 miuy = mean(Q_rawdata);
            %                 %                                 %                                 I2_bar = mean((I_rawdata-miux).^2);
            %                 %                                 %                                 Q2_bar = mean((Q_rawdata-miuy).^2);
            %                 %                                 %                                 IQ_bar = mean((I_rawdata-miux).*(Q_rawdata-miuy));
            %                 %                                 %                                 D_bar = IQ_bar/I2_bar;
            %                 %                                 %                                 C_bar = sqrt(Q2_bar/I2_bar-D_bar^2);
            %                 %                                 %                                 d_ampImb = sqrt(C_bar^2+D_bar^2)-1;
            %                 %                                 %                                 phi = atan(D_bar/C_bar);
            %                 %                                 %                                 I_rawdata = I_rawdata-miux;
            %                 %                                 %                                 Q_rawdata =((Q_rawdata-miuy)/(1+d_ampImb)-I_rawdata*sin(phi))/cos(phi);
            %                 %
            %                 %                                 signalcomplex = I_rawdata+1i*Q_rawdata;
            %                 %
            %                 %                                 % Data decimation
            %                 %                                 samp_rate_dec = app.sampRate/app.dec;
            %                 %                                 DeciData = decimate(signalcomplex, app.dec);
            %                 %                                 Xtime = (1:length(DeciData))/samp_rate_dec;
            %                 %
            %                 %                                 I_DeciData = real(DeciData);
            %                 %                                 Q_DeciData = imag(DeciData);
            %                 %
            %                 %                                 if app.time_scope_rebuild
            %                 %                                     % Data stream
            %                 %                                     app.UIAxes_timeScope.cla;
            %                 %                                     Yaxis = NaN(2, length(Xtime));
            %                 %                                     app.hLine_time_scope = line(app.UIAxes_timeScope, Xtime, Yaxis, 'LineWidth', 3);
            %                 %                                     app.hLine_time_scope(1).Color = [1 1 0];
            %                 %                                     app.hLine_time_scope(2).Color = [0 1 0];
            %                 %                                     % Amp_max = max(max(I_DeciData), max(Q_DeciData));
            %                 %                                     axis(app.UIAxes_timeScope,[0 max(Xtime) -inf inf]);
            %                 %                                     xlabel(app.UIAxes_timeScope, 'Time(s)');
            %                 %                                     ylabel(app.UIAxes_timeScope, 'Amplitude');
            %                 %                                     legend(app.UIAxes_timeScope, {'I-Channel', 'Q-Channel'},'FontSize', 12, 'TextColor', 'k',...
            %                 %                                         'Color',[0.65,0.65,0.65]); % Create the legend
            %                 %                                     set(app.UIAxes_timeScope, 'XGrid','off', 'YGrid', 'on', 'GridColor', [0.23 0.44 0.34], 'GridLineStyle', '--', 'GridAlpha', 0.8)
            %                 %                                     drawnow
            %                 %
            %                 %                                     app.time_scope_rebuild = 0;
            %                 %                                 else
            %                 %                                     app.hLine_time_scope(1).YData = I_DeciData;
            %                 %                                     app.hLine_time_scope(2).YData = Q_DeciData;
            %                 %                                     drawnow
            %                 %                                 end
            %                 %                             catch
            %                 %                                 continue;
            %                 %                             end
            %                 %                     end
            %                 %
            %                 %                     switch app.TabGroup_2.SelectedTab.Title
            %                 %                         case 'Velocity Scope'
            %                 %                             try
            %                 %                                 df_dopp = samp_rate_dec / app.Nfftv;
            %                 %                                 Doppler_psd = 10*log10((abs(fftshift(fft(DeciData,app.Nfftv))).^2 ...
            %                 %                                     /df_dopp)+eps);
            %                 %                                 [Doppler_psd_max, Doppler_psd_max_index] = max(Doppler_psd);
            %                 %
            %                 %                                 vel_axis = linspace(-app.velMax, app.velMax, app.Nfftv);
            %                 %                                 vel_max = vel_axis(Doppler_psd_max_index);
            %                 %
            %                 %                                 if app.velocity_scope_rebuild
            %                 %                                     app.UIAxes_velocityScope.cla;
            %                 %                                     app.hLine_velocity_scope = line( app.UIAxes_velocityScope,vel_axis, Doppler_psd, 'LineWidth', 3);
            %                 %                                     app.hLine_velocity_scope.Color = [0 1 0];
            %                 %                                     hold(app.UIAxes_velocityScope, "on")
            %                 %                                     axis(app.UIAxes_velocityScope, [-app.velMax, app.velMax, 0, inf]);
            %                 %                                     xlabel(app.UIAxes_velocityScope,'Velocity (m/s)');
            %                 %                                     ylabel(app.UIAxes_velocityScope,'Intensity (dB)');
            %                 %                                     app.hLineV_velocity_scope = line(app.UIAxes_velocityScope, [vel_max vel_max],[0 Doppler_psd_max], 'LineStyle', '--', 'Color', [1 1 0], 'LineWidth', 3);
            %                 %                                     label = sprintf('Detected Velocity: %4.2f m/s ', vel_max);
            %                 %                                     app.hText_velocity_scope = text(app.UIAxes_velocityScope, vel_max+1, Doppler_psd_max-1, label, 'Color', [1 1 0]);
            %                 %                                     grid(app.UIAxes_velocityScope, 'on');
            %                 %                                     set(app.UIAxes_velocityScope, 'GridColor', [0.23 0.44 0.34], 'GridLineStyle', '--', 'GridAlpha', 0.8)
            %                 %                                     drawnow
            %                 %
            %                 %                                     app.velocity_scope_rebuild = 0;
            %                 %                                 else
            %                 %                                     app.hLine_velocity_scope.YData = Doppler_psd;
            %                 %                                     app.hLineV_velocity_scope.XData = [vel_max, vel_max];
            %                 %                                     app.hLineV_velocity_scope.YData = [0 Doppler_psd_max];
            %                 %                                     label = sprintf('Detected Range: %4.2f m/s ', vel_max);
            %                 %                                     app.hText_velocity_scope.String = label;
            %                 %                                     app.hText_velocity_scope.Position = [vel_max+1, Doppler_psd_max-1];
            %                 %                                     axis(app.axes_velocity_scope, [-app.velMax, app.velMax, 0, max(Doppler_psd_max,1)]);
            %                 %                                     drawnow
            %                 %                                 end
            %                 %                             catch
            %                 %                                 continue;
            %                 %                             end
            %                 %
            %                 %                         case 'Velocity Waterfall'
            %                 %                             try
            %                 %                                 df_dopp = samp_rate_dec / app.Nfftv;
            %                 %                                 Doppler_psd = 10*log10((abs(fftshift(fft(DeciData,app.Nfftv))).^2 ...
            %                 %                                     /df_dopp)+eps);
            %                 %
            %                 %                                 vel_axis = linspace(-app.velMax,app.velMax,app.Nfftv);
            %                 %
            %                 %                                 if app.Velocity_WF_flag
            %                 %                                     m = app.HistorysizeframeSpinner.Value;
            %                 %                                     Velocity_WF = zeros(m, app.Nfftv);
            %                 %                                     app.Velocity_WF_flag = 0;
            %                 %                                 end
            %                 %                                 try
            %                 %                                     Velocity_WF(2:m,:) = Velocity_WF(1:m-1,:);
            %                 %                                     Velocity_WF(1,:) = Doppler_psd;
            %                 %                                 catch
            %                 %                                     app.Velocity_WF_flag = 1;
            %                 %                                     continue
            %                 %                                 end
            %                 %
            %                 %                                 if app.velocity_waterfall_rebuild
            %                 %                                     app.UIAxes_velocityWaterfall.cla;
            %                 %                                     app.hVelocity_WF = imagesc(app.UIAxes_velocityWaterfall, vel_axis, (0:m-1), Velocity_WF);
            %                 %                                     axis(app.UIAxes_velocityWaterfall, [-app.velMax app.velMax 0 m-1]);
            %                 %                                     % dynamic range
            %                 %                                     clim_max = app.DRMaxKnob.Value;
            %                 %                                     clim_min = app.DRMinKnob.Value;
            %                 %                                     set(app.UIAxes_velocityWaterfall, 'CLim', [clim_min,clim_max]);
            %                 %                                     shading(app.UIAxes_velocityWaterfall, 'interp');
            %                 %                                     xlabel(app.UIAxes_velocityWaterfall,'Velocity (m/s)');
            %                 %                                     ylabel(app.UIAxes_velocityWaterfall,'Frame');
            %                 %                                     legend(app.UIAxes_velocityWaterfall,'off')
            %                 %                                     drawnow
            %                 %
            %                 %                                     app.velocity_waterfall_rebuild = 0;
            %                 %                                 else
            %                 %                                     app.hVelocity_WF.CData = Velocity_WF;
            %                 %                                     drawnow
            %                 %                                 end
            %                 %
            %                 %                                 if app.DRAutoCheckBox.Value
            %                 %                                     Velocity_WF(isinf(Velocity_WF)) = 0;
            %                 %                                     AutoDRMaxVal = max(max(Velocity_WF));
            %                 %                                     AutoDRMaxVal = max(AutoDRMaxVal, 88);
            %                 %
            %                 %                                     [row,col] = size(Velocity_WF);
            %                 %                                     AutoDRMinVal = sum(sum(Velocity_WF))/(row*col);
            %                 %                                     AutoDRMinVal = max(AutoDRMinVal, 40);
            %                 %
            %                 %                                     app.DRMaxKnob.Value = AutoDRMaxVal;
            %                 %                                     app.DRMinKnob.Value = AutoDRMinVal;
            %                 %                                     % clim = get(gca,'CLim');
            %                 %                                     set(app.UIAxes_velocityWaterfall, 'CLim', [AutoDRMinVal, AutoDRMaxVal]);
            %                 %                                 end
            %                 %                             catch
            %                 %                                 continue;
            %                 %                             end
            %                 %                     end
            %                 %                 end
            %
            %                 if strcmp(app.StreamingSwitch.Value, 'Off')
            %                     app.StreamingOnLamp.Color = 'w';
            %                     app.StreamingOffLamp.Color = 'r';
            %                     app.SaveRealtimestreamCheckBox.Enable = 'off';
            %                     break;
            %                 end
            %             end
        end

        % Value changed function: Tx1CheckBox
        function Tx1CheckBoxValueChanged(app, event)
            value = app.Tx1CheckBox.Value;
            % total number of active Tx
            app.nTx = sum(app.Tx1CheckBox.Value + app.Tx2CheckBox.Value);
            if app.nTx == 2 % when tx2 was on and tx1 was off, clicking on tx1
                switch app.ModulationDropDown.Value
                    case 'FMCW Sawtooth'
                        app.cmd_setTx = uint16(zeros(app.cmd_len, 1));
                        app.cmd_setTx(1:app.cmd_rep) = app.setTx_h + app.txOpt.ch12;
                        app.ViewingTxDropDown.Items = {'1','2'};
                    case 'CW'
                        % Prevent multiple transmitter from operating in CW mode
                        app.cmd_setTx = uint16(zeros(app.cmd_len, 1));
                        app.cmd_setTx(1:app.cmd_rep) = app.setTx_h + app.txOpt.ch1;
                        app.Tx2CheckBox.Value = 0;
                        app.nTx = 1;
                        app.ViewingTxDropDown.Items = {'1'};
                end
            elseif app.nTx == 1 && value == 0 % when tx1 and tx2 were on, clicking off tx1
                app.cmd_setTx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setTx(1:app.cmd_rep) = app.setTx_h + app.txOpt.ch2;
                
                app.ViewingTxDropDown.Items = {'2'};
            else % when tx2 was off and tx1 was on, clicking off tx1; there has to be at least 1 active Tx
                app.Tx1CheckBox.Value = 1;
                app.nTx = 1;
                app.ViewingTxDropDown.Items = {'1'};
            end
            sdradar_send_data(app.cmd_setTx);
            plot_rebuild(app, 1);
            update_calculated_param(app);
            app.SamplesPerSweepDropDownValueChanged;
        end

        % Value changed function: Tx2CheckBox
        function Tx2CheckBoxValueChanged(app, event)
            value = app.Tx2CheckBox.Value;
            % total number of active Tx
            app.nTx = sum(app.Tx1CheckBox.Value + app.Tx2CheckBox.Value);
            if app.nTx == 2 % when tx2 was off and tx1 was on, clicking on tx2
                switch app.ModulationDropDown.Value
                    case 'FMCW Sawtooth'
                        app.cmd_setTx = uint16(zeros(app.cmd_len, 1));
                        app.cmd_setTx(1:app.cmd_rep) = app.setTx_h + app.txOpt.ch12;
                        app.ViewingTxDropDown.Items = {'1','2'};
                    case 'CW'
                        % Prevent multiple transmitter from operating in CW mode
                        app.cmd_setTx = uint16(zeros(app.cmd_len, 1));
                        app.cmd_setTx(1:app.cmd_rep) = app.setTx_h + app.txOpt.ch2;
                        app.Tx1CheckBox.Value = 0;
                        app.nTx = 1;
                        app.ViewingTxDropDown.Items = {'2'};
                end
            elseif app.nTx == 1 && value == 0  % when tx1 and tx2 were on, clicking off tx2
                app.cmd_setTx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setTx(1:app.cmd_rep) = app.setTx_h + app.txOpt.ch1;
                
                app.ViewingTxDropDown.Items = {'1'};
            else %  when tx2 was on and tx1 was off, clicking off tx2; there has to be at least 1 active Tx
                app.Tx2CheckBox.Value = 1;
                app.nTx = 1;
                app.ViewingTxDropDown.Items = {'2'};
            end
            sdradar_send_data(app.cmd_setTx);
            plot_rebuild(app, 1);
            update_calculated_param(app);
            app.SamplesPerSweepDropDownValueChanged;
        end

        % Value changed function: Rx1CheckBox
        function Rx1CheckBoxValueChanged(app, event)
            value = app.Rx1CheckBox.Value;
            % total number of active Tx
            app.nRx = sum(app.Rx1CheckBox.Value + app.Rx2CheckBox.Value + ...
                app.Rx3CheckBox.Value + app.Rx4CheckBox.Value);  % number of active receivers
            
            if app.nRx == 4
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch1234;
                
                app.ViewingRxDropDown.Items = {'1','2','3','4'};
            elseif app.nRx == 3 && value == 0
                % Rx channel only works in pairs, Rx1 & Rx2 or Rx3 & Rx4.
                app.Rx2CheckBox.Value = 0;
                app.Rx3CheckBox.Value = 1;
                app.Rx4CheckBox.Value = 1;
                app.nRx = 2;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch34;
                
                app.ViewingRxDropDown.Items = {'3','4'};
            elseif app.nRx == 3 && value == 1
                app.Rx2CheckBox.Value = 1;
                app.Rx3CheckBox.Value = 1;
                app.Rx4CheckBox.Value = 1;
                app.nRx = 4;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch1234;
                
                app.ViewingRxDropDown.Items = {'1','2','3','4'};
            elseif app.nRx == 2 && value == 0
                app.Rx2CheckBox.Value = 0;
                app.Rx3CheckBox.Value = 1;
                app.Rx4CheckBox.Value = 1;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch34;
                
                app.ViewingRxDropDown.Items = {'3','4'};
            elseif app.nRx == 2 && value == 1
                app.Rx2CheckBox.Value = 1;
                app.Rx3CheckBox.Value = 0;
                app.Rx4CheckBox.Value = 0;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch12;
                
                app.ViewingRxDropDown.Items = {'1','2'};
            elseif app.nRx == 1 && value == 1
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch1;
                
                app.ViewingRxDropDown.Items = {'1'};
            elseif app.nRx == 1 && value == 0
                activeInd = find([app.Rx1CheckBox.Value app.Rx2CheckBox.Value app.Rx3CheckBox.Value app.Rx4CheckBox.Value]);
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                FieldNames = fieldnames(app.rxOpt);
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.(FieldNames{activeInd});
                
                app.ViewingRxDropDown.Items = {num2str(activeInd)};
            elseif app.nRx == 0
                % cannot be 0 Rx channel
                app.Rx1CheckBox.Value = 1;
                app.nRx = 1;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch1;
                
                app.ViewingRxDropDown.Items = {'1'};
            end
            sdradar_send_data(app.cmd_setRx);
            plot_rebuild(app, 1);
            update_calculated_param(app);
            app.nSamp_max = app.max_data_rate*app.sTime/app.nRx;
            app.SamplesPerSweepOptionUpdate;
            app.SamplesPerSweepDropDownValueChanged;
        end

        % Value changed function: Rx2CheckBox
        function Rx2CheckBoxValueChanged(app, event)
            value = app.Rx2CheckBox.Value;
            % total number of active Tx
            app.nRx = sum(app.Rx1CheckBox.Value + app.Rx2CheckBox.Value + ...
                app.Rx3CheckBox.Value + app.Rx4CheckBox.Value);  % number of active receivers
            
            if app.nRx == 4
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch1234;
                
                app.ViewingRxDropDown.Items = {'1','2','3','4'};
            elseif app.nRx == 3 && value == 0
                % Rx channel only works in pairs, Rx1 & Rx2 or Rx3 & Rx4.
                app.Rx1CheckBox.Value = 0;
                app.Rx3CheckBox.Value = 1;
                app.Rx4CheckBox.Value = 1;
                app.nRx = 2;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch34;
                
                app.ViewingRxDropDown.Items = {'3','4'};
            elseif app.nRx == 3 && value == 1
                app.Rx1CheckBox.Value = 1;
                app.Rx3CheckBox.Value = 1;
                app.Rx4CheckBox.Value = 1;
                app.nRx = 4;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch1234;
                
                app.ViewingRxDropDown.Items = {'1','2','3','4'};
            elseif app.nRx == 2 && value == 0
                app.Rx1CheckBox.Value = 0;
                app.Rx3CheckBox.Value = 1;
                app.Rx4CheckBox.Value = 1;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch34;
                
                app.ViewingRxDropDown.Items = {'3','4'};
            elseif app.nRx == 2 && value == 1
                app.Rx1CheckBox.Value = 1;
                app.Rx3CheckBox.Value = 0;
                app.Rx4CheckBox.Value = 0;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch12;
                
                app.ViewingRxDropDown.Items = {'1','2'};
            elseif app.nRx == 1 && value == 1
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch2;
                
                app.ViewingRxDropDown.Items = {'2'};
            elseif app.nRx == 1 && value == 0
                activeInd = find([app.Rx1CheckBox.Value app.Rx2CheckBox.Value app.Rx3CheckBox.Value app.Rx4CheckBox.Value]);
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                FieldNames = fieldnames(app.rxOpt);
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.(FieldNames{activeInd});
                
                app.ViewingRxDropDown.Items = {num2str(activeInd)};
            elseif app.nRx == 0
                % cannot be 0 Rx channel
                app.Rx2CheckBox.Value = 1;
                app.nRx = 1;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch2;
                
                app.ViewingRxDropDown.Items = {'2'};
            end
            sdradar_send_data(app.cmd_setRx);
            plot_rebuild(app, 1);
            update_calculated_param(app);
            app.nSamp_max = app.max_data_rate*app.sTime/app.nRx;
            app.SamplesPerSweepOptionUpdate;
            app.SamplesPerSweepDropDownValueChanged;
        end

        % Value changed function: Rx3CheckBox
        function Rx3CheckBoxValueChanged(app, event)
            value = app.Rx3CheckBox.Value;
            % total number of active Tx
            app.nRx = sum(app.Rx1CheckBox.Value + app.Rx2CheckBox.Value + ...
                app.Rx3CheckBox.Value + app.Rx4CheckBox.Value);  % number of active receivers
            
            if app.nRx == 4
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch1234;
                
                app.ViewingRxDropDown.Items = {'1','2','3','4'};
            elseif app.nRx == 3 && value == 0
                % Rx channel only works in pairs, Rx1 & Rx2 or Rx3 & Rx4.
                app.Rx1CheckBox.Value = 1;
                app.Rx2CheckBox.Value = 1;
                app.Rx4CheckBox.Value = 0;
                app.nRx = 2;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch12;
                
                app.ViewingRxDropDown.Items = {'1','2'};
            elseif app.nRx == 3 && value == 1
                app.Rx1CheckBox.Value = 1;
                app.Rx2CheckBox.Value = 1;
                app.Rx4CheckBox.Value = 1;
                app.nRx = 4;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch1234;
                
                app.ViewingRxDropDown.Items = {'1','2','3','4'};
            elseif app.nRx == 2 && value == 0
                app.Rx1CheckBox.Value = 1;
                app.Rx2CheckBox.Value = 1;
                app.Rx4CheckBox.Value = 0;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch12;
                
                app.ViewingRxDropDown.Items = {'1','2'};
            elseif app.nRx == 2 && value == 1
                app.Rx1CheckBox.Value = 0;
                app.Rx2CheckBox.Value = 0;
                app.Rx4CheckBox.Value = 1;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch34;
                
                app.ViewingRxDropDown.Items = {'3','4'};
            elseif app.nRx == 1 && value == 1
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch3;
                
                app.ViewingRxDropDown.Items = {'3'};
            elseif app.nRx == 1 && value == 0
                activeInd = find([app.Rx1CheckBox.Value app.Rx2CheckBox.Value app.Rx3CheckBox.Value app.Rx4CheckBox.Value]);
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                FieldNames = fieldnames(app.rxOpt);
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.(FieldNames{activeInd});
                
                app.ViewingRxDropDown.Items = {num2str(activeInd)};
            elseif app.nRx == 0
                % cannot be 0 Rx channel
                app.Rx3CheckBox.Value = 1;
                app.nRx = 1;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch3;
                
                app.ViewingRxDropDown.Items = {'3'};
            end
            sdradar_send_data(app.cmd_setRx);
            plot_rebuild(app, 1);
            update_calculated_param(app);
            app.nSamp_max = app.max_data_rate*app.sTime/app.nRx;
            app.SamplesPerSweepOptionUpdate;
            app.SamplesPerSweepDropDownValueChanged;
        end

        % Value changed function: Rx4CheckBox
        function Rx4CheckBoxValueChanged(app, event)
            value = app.Rx4CheckBox.Value;
            % total number of active Tx
            app.nRx = sum(app.Rx1CheckBox.Value + app.Rx2CheckBox.Value + ...
                app.Rx3CheckBox.Value + app.Rx4CheckBox.Value);  % number of active receivers
            
            if app.nRx == 4
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch1234;
                
                app.ViewingRxDropDown.Items = {'1','2','3','4'};
            elseif app.nRx == 3 && value == 0
                % Rx channel only works in pairs, Rx1 & Rx2 or Rx3 & Rx4.
                app.Rx1CheckBox.Value = 1;
                app.Rx2CheckBox.Value = 1;
                app.Rx3CheckBox.Value = 0;
                app.nRx = 2;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch12;
                
                app.ViewingRxDropDown.Items = {'1','2'};
            elseif app.nRx == 3 && value == 1
                app.Rx1CheckBox.Value = 1;
                app.Rx2CheckBox.Value = 1;
                app.Rx3CheckBox.Value = 1;
                app.nRx = 4;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch1234;
                
                app.ViewingRxDropDown.Items = {'1','2','3','4'};
            elseif app.nRx == 2 && value == 0
                app.Rx1CheckBox.Value = 1;
                app.Rx2CheckBox.Value = 1;
                app.Rx3CheckBox.Value = 0;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch12;
                
                app.ViewingRxDropDown.Items = {'1','2'};
            elseif app.nRx == 2 && value == 1
                app.Rx1CheckBox.Value = 0;
                app.Rx2CheckBox.Value = 0;
                app.Rx3CheckBox.Value = 1;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch34;
                
                app.ViewingRxDropDown.Items = {'3','4'};
            elseif app.nRx == 1 && value == 1
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch4;
                
                app.ViewingRxDropDown.Items = {'4'};
            elseif app.nRx == 1 && value == 0
                activeInd = find([app.Rx1CheckBox.Value app.Rx2CheckBox.Value app.Rx3CheckBox.Value app.Rx4CheckBox.Value]);
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                FieldNames = fieldnames(app.rxOpt);
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.(FieldNames{activeInd});
                
                app.ViewingRxDropDown.Items = {num2str(activeInd)};
            elseif app.nRx == 0
                % cannot be 0 Rx channel
                app.Rx4CheckBox.Value = 1;
                app.nRx = 1;
                app.cmd_setRx = uint16(zeros(app.cmd_len, 1));
                app.cmd_setRx(1:app.cmd_rep) = app.setRx_h + app.rxOpt.ch4;
                
                app.ViewingRxDropDown.Items = {'4'};
            end
            sdradar_send_data(app.cmd_setRx);
            plot_rebuild(app, 1);
            update_calculated_param(app);
            app.nSamp_max = app.max_data_rate*app.sTime/app.nRx;
            app.SamplesPerSweepOptionUpdate;
            app.SamplesPerSweepDropDownValueChanged;
        end

        % Value changed function: DRMinKnob
        function DRMinKnobValueChanged(app, event)
            % value = app.DRMinKnob.Value;
            plot_rebuild(app, 1);
        end

        % Value changed function: DRMaxKnob
        function DRMaxKnobValueChanged(app, event)
            % value = app.DRMaxKnob.Value;
            plot_rebuild(app, 1);
        end

        % Value changed function: HistorysizeframeSpinner
        function HistorysizeframeSpinnerValueChanged(app, event)
            app.Range_WF_flag = 1;
            app.Velocity_WF_flag = 1;
            plot_rebuild(app, 1);
        end

        % Value changing function: RangeNotchSlider
        function RangeNotchSliderValueChanging(app, event)
            % changingValue = event.Value;
            
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            
            d = uiprogressdlg(app.AncortekSDRadarUIFigure,'Title','Recording data');
            
            % need to send something to the kit before each data
            % requesting session
            sdradar_send_data(uint16(zeros(app.cmd_len, 1)));
            
            % Request data
            num_of_sweeps = app.RecordSpinner.Value / app.sTime;
            % * 2: I and Q two channels
            data_length = ceil(num_of_sweeps*app.nSamp*2*app.nRx/app.USB_BUFFER_BLOCK_SIZE)...
                *app.USB_BUFFER_BLOCK_SIZE+app.USB_BUFFER_LEFTOVER;
            DATE = datestr(now);
            ADCsamples = sdradar_get_data(data_length);
            
            % Discard usb leftover data samples
            ADCsamples = double(ADCsamples(app.USB_BUFFER_LEFTOVER:end));
            
            % Prompt window
            DATE_vec = datevec(DATE);
            filename = sprintf('%2.f_%2.f_%2.f_%2.f_%2.f_%2.f', DATE_vec(1), DATE_vec(2), ...
                DATE_vec(3), DATE_vec(4), DATE_vec(5), DATE_vec(6));
            [file, path] = uiputfile({'.mat';'.txt'},'Save file name',filename);
            filename = [path, file];
            if file == 0
                close(d);
                return
            end
            
            if strcmp(file(end-3:end), '.mat')
                fStart = app.fStart;
                fStop = app.fStop;
                sTime = app.sTime;
                nSamp = app.nSamp;
                nRx = app.nRx;
                nTx = app.nTx;
                save(filename, 'DATE','ADCsamples','fStart','fStop','sTime','nSamp',...
                    'nRx', 'nTx');
            else
                fID = fopen(filename,'w');
                fprintf(fID, '%d\n', app.nRx);
                fprintf(fID, '%d\n', app.nTx);
                fprintf(fID, '%d\n', app.fStart);
                fprintf(fID, '%d\n', app.fStop);
                fprintf(fID, '%d\n', app.sTime);
                fprintf(fID, '%d\n', app.nSamp);
                fprintf(fID, '%d\n', ADCsamples);
                fclose(fID);
            end
            
            % Close the dialog box
            close(d);
        end

        % Menu selected function: SavecurrentconfigMenu
        function SavecurrentconfigMenuSelected(app, event)
            DATE = datestr(now);
            DATE_vec = datevec(DATE);
            filename = sprintf('sdradar_config_%2.f_%2.f_%2.f_%2.f_%2.f_%2.f', DATE_vec(1), DATE_vec(2), ...
                DATE_vec(3), DATE_vec(4), DATE_vec(5), DATE_vec(6));
            [file, path] = uiputfile('.mat','Save file name',filename);
            filename = [path, file];
            if file == 0
                close(d);
                return
            else
                % key variables
                nTx = app.nTx;
                nRx = app.nRx;
                sTime = app.sTime;
                nSamp = app.nSamp;
                bw_max_GHz = app.bw_max_GHz;
                
                % GUI values
                RampstartGHzSlider.Value = app.RampstartGHzSlider.Value;
                RampstopGHzSlider.Value = app.RampstopGHzSlider.Value;
                RampstartGHzEditField.Value=app.RampstartGHzEditField.Value;
                RampstopGHzEditField.Value=app.RampstopGHzEditField.Value;
                Tx1CheckBox.Value = app.Tx1CheckBox.Value;
                Tx2CheckBox.Value = app.Tx2CheckBox.Value;
                Rx1CheckBox.Value = app.Rx1CheckBox.Value;
                Rx2CheckBox.Value = app.Rx2CheckBox.Value;
                Rx3CheckBox.Value = app.Rx3CheckBox.Value;
                Rx4CheckBox.Value = app.Rx4CheckBox.Value;
                SweeptimeusDropDown.Value = app.SweeptimeusDropDown.Value;
                SamplesPerSweepDropDown.Value = app.SamplesPerSweepDropDown.Value;
                SamplesPerSweepDropDown.Items = app.SamplesPerSweepDropDown.Items;
                
                % command values
                cmd_setSt = app.cmd_setSt;
                cmd_setMod = app.cmd_setMod;
                cmd_setRx = app.cmd_setRx;
                cmd_setTx = app.cmd_setTx;
                cmd_setSamp = app.cmd_setSamp;
                
                save(filename,'nTx','nRx','sTime','nSamp','bw_max_GHz','RampstartGHzSlider','RampstopGHzSlider','RampstartGHzEditField','RampstopGHzEditField', ...
                    'Tx1CheckBox','Tx2CheckBox','Rx1CheckBox','Rx2CheckBox','Rx3CheckBox','Rx4CheckBox', ...
                    'SweeptimeusDropDown','SamplesPerSweepDropDown','cmd_*');
            end
        end

        % Menu selected function: LoadapreviousconfigMenu
        function LoadapreviousconfigMenuSelected(app, event)
            % [file,path] = uigetfile('*.mat', 'Select an Sdradar Configuration File');
            uiopen('load');
            try
                app.nTx = nTx;
                app.nRx = nRx;
                app.sTime = sTime;
                app.nSamp = nSamp;
                app.bw_max_GHz = bw_max_GHz;
                
                app.RampstartGHzSlider.Value = RampstartGHzSlider.Value;
                app.RampstopGHzSlider.Value = RampstopGHzSlider.Value;
                app.RampstartGHzEditField.Value = RampstartGHzEditField.Value;
                app.RampstopGHzEditField.Value = RampstopGHzEditField.Value;
                app.Tx1CheckBox.Value = Tx1CheckBox.Value;
                app.Tx2CheckBox.Value = Tx2CheckBox.Value;
                app.Rx1CheckBox.Value = Rx1CheckBox.Value;
                app.Rx2CheckBox.Value = Rx2CheckBox.Value;
                app.Rx3CheckBox.Value = Rx3CheckBox.Value;
                app.Rx4CheckBox.Value = Rx4CheckBox.Value;
                app.SweeptimeusDropDown.Value = SweeptimeusDropDown.Value;
                app.SamplesPerSweepDropDown.Items = SamplesPerSweepDropDown.Items;
                app.SamplesPerSweepDropDown.Value = SamplesPerSweepDropDown.Value;
                
                app.cmd_setSt = cmd_setSt;
                app.cmd_setMod = cmd_setMod;
                app.cmd_setTx = cmd_setTx;
                app.cmd_setRx = cmd_setRx;
                app.cmd_setSamp = cmd_setSamp;
                
                sdradar_send_data(app.cmd_setSt);
                sdradar_send_data(app.cmd_setMod);
                sdradar_send_data(app.cmd_setTx);
                sdradar_send_data(app.cmd_setRx);
                sdradar_send_data(app.cmd_setSamp);
                app.bw_update;
            catch
                app.MessageEditField.Value = 'Loading configuration failed.';
                app.MessageLamp.Color = 'Red';
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msg = 'Version 1.0';
            title = 'About';
            uiconfirm(app.AncortekSDRadarUIFigure, msg, title, 'Icon', 'info', 'Options',{'OK'});
        end

        % Value changed function: HighPassFilterCheckBox
        function HighPassFilterCheckBoxValueChanged(app, event)
            value = app.HighPassFilterCheckBox.Value;
            
            % User has selected to apply the HighPass Filter
            if (value)
                
                
            end
            
        end

        % Value changed function: PCACheckBox
        function PCACheckBoxValueChanged(app, event)
            value = app.PCACheckBox.Value;
            
            % Turn ON and OFF PCA component selection
            if (value)
                app.PrincipalComponentsSpinner.Enable ="on";
                app.numPCA_components = app.PrincipalComponentsSpinner.Value;
            else
                app.PrincipalComponentsSpinner.Enable = "off";
            end
            
        end

        % Value changed function: PrincipalComponentsSpinner
        function PrincipalComponentsSpinnerValueChanged(app, event)
            
            % user has changed the value of number of Principal Components.
            app.numPCA_components = app.PrincipalComponentsSpinner.Value;
            
            % Check if user has entered number of PCA components more than
            % number of samples.
            if(app.numPCA_components > app.nSamp)
                
                app.numPCA_components = app.nSamp;
                
            end
            
            
        end

        % Value changed function: SweepstoProcessSpinner
        function SweepstoProcessSpinnerValueChanged(app, event)
            
            app.PN = app.SweepstoProcessSpinner.Value;
            app.mean_doppler_shift_rebuild = 1;
            app.spectrogram_scope_rebuild = 1;
        end

        % Value changed function: SaveRealtimestreamCheckBox
        function SaveRealtimestreamCheckBoxValueChanged(app, event)
            
            app.saveStream = app.SaveRealtimestreamCheckBox.Value;
            if(app.saveStream)
                
                app.stream_save_dir = strcat(pwd, '\', 'streamedData');
                
                if ~exist(app.stream_save_dir, 'dir')
                    mkdir(app.stream_save_dir);
                end
                
            end
            
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            
            if(app.StartButton.Value == 1)
                
                app.RecordLamp.Color = 'g';
            end
            
            dirCheck = checkIfDirExist(app);
            
            while(app.StartButton.Value == 1 && dirCheck)
                
                % Now we are ready to record data
                
                % need to send something to the kit before each data
                % requesting session
                sdradar_send_data(uint16(zeros(app.cmd_len, 1)));
                
                % Request data
                num_of_sweeps = app.SecondsSpinner.Value / app.sTime;
                
                % * 2: I and Q two channels
                data_length = ceil((num_of_sweeps+2)*app.nSamp*2*app.nRx/app.USB_BUFFER_BLOCK_SIZE)...
                    *app.USB_BUFFER_BLOCK_SIZE+app.USB_BUFFER_LEFTOVER;
                DATE = datestr(now);
                
                
                ADCsamples = sdradar_get_data(data_length);
                
                % Discard usb leftover data samples
                ADCsamples = double(ADCsamples(app.USB_BUFFER_LEFTOVER:end));
                
                % Prepare filename where the data will be saved
                out_file_name = strcat(app.stream_save_dir,'\',string(datetime('now','Format','d-MMM-y_HH-mm-ss')), '.mat');
                
                fStart = app.fStart;
                fStop = app.fStop;
                sTime = app.sTime;
                nSamp = app.nSamp;
                nRx = app.nRx;
                nTx = app.nTx;
                
                save(out_file_name, 'DATE','ADCsamples','fStart','fStop','sTime','nSamp',...
                    'nRx', 'nTx', 'num_of_sweeps');
                
                % To update the GUI state
                if(app.StartButton.Value == 1)
                    
                    app.StartButton.Value = 0;
                    
                end 
                
                drawnow
               
            end
            if(app.StartButton.Value == 0)
                
                app.RecordLamp.Color = 'r';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AncortekSDRadarUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {898, 898, 898};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {898, 898};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {283, '1x', 265};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AncortekSDRadarUIFigure = uifigure('Visible', 'off');
            app.AncortekSDRadarUIFigure.Name = 'Ancortek SDRadar';
            app.AncortekSDRadarUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.AncortekSDRadarUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.LeftPanel);
            app.SDRKITInformationPanel = uipanel(app.GridLayout7);
            app.SDRKITInformationPanel.Title = 'SDR-KIT Information';
            app.GridLayout5 = uigridlayout(app.SDRKITInformationPanel);
            app.RFSNEditFieldLabel = uilabel(app.GridLayout5);
            app.RFSNEditFieldLabel.Text = 'RF S/N';
            app.RFSNEditField = uieditfield(app.GridLayout5, 'text');
            app.RFSNEditField.Editable = 'off';
            app.PMSNEditFieldLabel = uilabel(app.GridLayout5);
            app.PMSNEditFieldLabel.Text = 'PM S/N';
            app.PMSNEditField = uieditfield(app.GridLayout5, 'text');
            app.PMSNEditField.Editable = 'off';
            app.ModelEditField = uieditfield(app.GridLayout5, 'text');
            app.ModelEditField.Editable = 'off';
            app.ModelDetectionButton = uibutton(app.GridLayout5, 'push');
            app.ModelDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ModelDetectionButtonPushed, true);
            app.ModelDetectionButton.Text = 'Model Detection';
            app.BandwidthControlPanel = uipanel(app.GridLayout7);
            app.BandwidthControlPanel.Title = 'Bandwidth Control';
            app.GridLayout4 = uigridlayout(app.BandwidthControlPanel);
            app.RampstartGHzSlider = uislider(app.GridLayout4);
            app.RampstartGHzSlider.ValueChangedFcn = createCallbackFcn(app, @RampstartGHzSliderValueChanged, true);
            app.RampstartGHzSlider.ValueChangingFcn = createCallbackFcn(app, @RampstartGHzSliderValueChanging, true);
            app.RampstopGHzSlider = uislider(app.GridLayout4);
            app.RampstopGHzSlider.ValueChangedFcn = createCallbackFcn(app, @RampstopGHzSliderValueChanged, true);
            app.RampstopGHzSlider.ValueChangingFcn = createCallbackFcn(app, @RampstopGHzSliderValueChanging, true);
            app.RampstartGHzEditFieldLabel = uilabel(app.GridLayout4);
            app.RampstartGHzEditFieldLabel.Text = 'Ramp start (GHz)';
            app.RampstartGHzEditField = uieditfield(app.GridLayout4, 'numeric');
            app.RampstartGHzEditField.ValueDisplayFormat = '%.3f';
            app.RampstartGHzEditField.Editable = 'off';
            app.RampstopGHzEditFieldLabel = uilabel(app.GridLayout4);
            app.RampstopGHzEditFieldLabel.Text = 'Ramp stop (GHz)';
            app.RampstopGHzEditField = uieditfield(app.GridLayout4, 'numeric');
            app.RampstopGHzEditField.ValueDisplayFormat = '%.3f';
            app.ActiveChannelSelectionPanel = uipanel(app.GridLayout7);
            app.ActiveChannelSelectionPanel.Title = 'Active Channel Selection';
            app.GridLayout3 = uigridlayout(app.ActiveChannelSelectionPanel);
            app.Tx1CheckBox = uicheckbox(app.GridLayout3);
            app.Tx1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Tx1CheckBoxValueChanged, true);
            app.Tx1CheckBox.Text = 'Tx 1';
            app.Tx2CheckBox = uicheckbox(app.GridLayout3);
            app.Tx2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Tx2CheckBoxValueChanged, true);
            app.Tx2CheckBox.Text = 'Tx 2';
            app.Rx1CheckBox = uicheckbox(app.GridLayout3);
            app.Rx1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Rx1CheckBoxValueChanged, true);
            app.Rx1CheckBox.Text = 'Rx 1';
            app.Rx2CheckBox = uicheckbox(app.GridLayout3);
            app.Rx2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Rx2CheckBoxValueChanged, true);
            app.Rx2CheckBox.Text = 'Rx 2';
            app.Rx3CheckBox = uicheckbox(app.GridLayout3);
            app.Rx3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Rx3CheckBoxValueChanged, true);
            app.Rx3CheckBox.Text = 'Rx 3';
            app.Rx4CheckBox = uicheckbox(app.GridLayout3);
            app.Rx4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Rx4CheckBoxValueChanged, true);
            app.Rx4CheckBox.Text = 'Rx 4';
            app.RadarParameterControlPanel = uipanel(app.GridLayout7);
            app.RadarParameterControlPanel.Title = 'Radar Parameter Control';
            app.GridLayout6 = uigridlayout(app.RadarParameterControlPanel);
            app.ModulationDropDownLabel = uilabel(app.GridLayout6);
            app.ModulationDropDownLabel.Text = 'Modulation';
            app.ModulationDropDown = uidropdown(app.GridLayout6);
            app.ModulationDropDown.Items = {'FMCW Sawtooth', 'CW'};
            app.ModulationDropDown.Enable = 'off';
            app.ModulationDropDown.Value = 'FMCW Sawtooth';
            app.SweeptimeusDropDownLabel = uilabel(app.GridLayout6);
            app.SweeptimeusDropDownLabel.Text = 'Sweep time (us)';
            app.SweeptimeusDropDown = uidropdown(app.GridLayout6);
            app.SweeptimeusDropDown.Items = {'125', '250', '500', '1000', '2000', '4000', '8000'};
            app.SweeptimeusDropDown.ValueChangedFcn = createCallbackFcn(app, @SweeptimeusDropDownValueChanged, true);
            app.SweeptimeusDropDown.Value = '1000';
            app.SamplesweepDropDownLabel = uilabel(app.GridLayout6);
            app.SamplesweepDropDownLabel.Text = 'Sample/sweep';
            app.SamplesPerSweepDropDown = uidropdown(app.GridLayout6);
            app.SamplesPerSweepDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplesPerSweepDropDownValueChanged, true);
            app.MessagePanel = uipanel(app.GridLayout7);
            app.MessagePanel.Title = 'Message';
            app.GridLayout8 = uigridlayout(app.MessagePanel);
            app.MessageEditField = uieditfield(app.GridLayout8, 'text');
            app.MessageEditField.Editable = 'off';
            app.MessageLamp = uilamp(app.GridLayout8);
            app.DataRecordingPanel = uipanel(app.GridLayout7);
            app.DataRecordingPanel.Title = 'Data Recording';
            app.GridLayout9 = uigridlayout(app.DataRecordingPanel);
            app.RecordButton = uibutton(app.GridLayout9, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Start';
            app.SecondsSpinnerLabel = uilabel(app.GridLayout9);
            app.SecondsSpinnerLabel.Text = 'Second(s)';
            app.RecordSpinner = uispinner(app.GridLayout9);
            app.RecordSpinner.Limits = [1 Inf];
            app.RecordSpinner.Value = 10;
            app.StreamingPanel = uipanel(app.GridLayout7);
            app.StreamingPanel.Title = 'Streaming';
            app.GridLayout10 = uigridlayout(app.StreamingPanel);
            app.StreamingSwitch = uiswitch(app.GridLayout10, 'slider');
            app.StreamingSwitch.ValueChangedFcn = createCallbackFcn(app, @StreamingSwitchValueChanged, true);
            app.StreamingOnLamp = uilamp(app.GridLayout10);
            app.StreamingOffLamp = uilamp(app.GridLayout10);
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.SpectrogramTab = uitab(app.TabGroup);
            app.SpectrogramTab.Title = 'Spectrogram';
            app.GridLayout22 = uigridlayout(app.SpectrogramTab);
            app.UIAxes_spectrogramScope = uiaxes(app.GridLayout22);
            xlabel(app.UIAxes_spectrogramScope, 'X')
            ylabel(app.UIAxes_spectrogramScope, 'Y')
            app.MeanDopplerShiftTab = uitab(app.TabGroup);
            app.MeanDopplerShiftTab.Title = 'Mean Doppler Shift';
            app.GridLayout23 = uigridlayout(app.MeanDopplerShiftTab);
            app.UIAxes_meanDopplerShiftScope = uiaxes(app.GridLayout23);
            xlabel(app.UIAxes_meanDopplerShiftScope, 'X')
            ylabel(app.UIAxes_meanDopplerShiftScope, 'Y')
            app.TimeScopeTab = uitab(app.TabGroup);
            app.TimeScopeTab.Title = 'Time Scope';
            app.GridLayout11 = uigridlayout(app.TimeScopeTab);
            app.UIAxes_timeScope = uiaxes(app.GridLayout11);
            xlabel(app.UIAxes_timeScope, 'X')
            ylabel(app.UIAxes_timeScope, 'Y')
            app.TabGroup_2 = uitabgroup(app.GridLayout2);
            app.RangeScopeTab = uitab(app.TabGroup_2);
            app.RangeScopeTab.Title = 'Range Scope';
            app.GridLayout14 = uigridlayout(app.RangeScopeTab);
            app.UIAxes_rangeScope = uiaxes(app.GridLayout14);
            xlabel(app.UIAxes_rangeScope, 'X')
            ylabel(app.UIAxes_rangeScope, 'Y')
            app.RangeDopplerTab = uitab(app.TabGroup_2);
            app.RangeDopplerTab.Title = 'Range Doppler';
            app.GridLayout15 = uigridlayout(app.RangeDopplerTab);
            app.UIAxes_rangeDoppler = uiaxes(app.GridLayout15);
            xlabel(app.UIAxes_rangeDoppler, 'X')
            ylabel(app.UIAxes_rangeDoppler, 'Y')
            app.RangeWaterfallTab = uitab(app.TabGroup_2);
            app.RangeWaterfallTab.Title = 'Range Waterfall';
            app.GridLayout18 = uigridlayout(app.RangeWaterfallTab);
            app.UIAxes_rangeWaterfall = uiaxes(app.GridLayout18);
            xlabel(app.UIAxes_rangeWaterfall, 'X')
            ylabel(app.UIAxes_rangeWaterfall, 'Y')
            app.VelocityScopeTab = uitab(app.TabGroup_2);
            app.VelocityScopeTab.Title = 'Velocity Scope';
            app.GridLayout19 = uigridlayout(app.VelocityScopeTab);
            app.UIAxes_velocityScope = uiaxes(app.GridLayout19);
            xlabel(app.UIAxes_velocityScope, 'X')
            ylabel(app.UIAxes_velocityScope, 'Y')
            app.VelocityWaterfallTab = uitab(app.TabGroup_2);
            app.VelocityWaterfallTab.Title = 'Velocity Waterfall';
            app.GridLayout20 = uigridlayout(app.VelocityWaterfallTab);
            app.UIAxes_velocityWaterfall = uiaxes(app.GridLayout20);
            xlabel(app.UIAxes_velocityWaterfall, 'X')
            ylabel(app.UIAxes_velocityWaterfall, 'Y')
            app.ViewingTxDropDownLabel = uilabel(app.GridLayout2);
            app.ViewingTxDropDownLabel.Text = 'Viewing Tx #';
            app.ViewingTxDropDown = uidropdown(app.GridLayout2);
            app.ViewingTxDropDown.Items = {'1', '2'};
            app.ViewingTxDropDown.Value = '1';
            app.ViewingRxDropDown = uidropdown(app.GridLayout2);
            app.ViewingRxDropDown.Items = {'1', '2', '3', '4'};
            app.ViewingRxDropDown.Value = '1';
            app.ViewingRxDropDownLabel = uilabel(app.GridLayout2);
            app.ViewingRxDropDownLabel.Text = 'Viewing Rx #';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout12 = uigridlayout(app.RightPanel);
            app.FilteringPanel = uipanel(app.GridLayout12);
            app.FilteringPanel.Title = 'Filtering';
            app.GridLayout13 = uigridlayout(app.FilteringPanel);
            app.RangeNotchFilterCheckBox = uicheckbox(app.GridLayout13);
            app.RangeNotchFilterCheckBox.Text = 'Range Notch Filter';
            app.RangeNotchSlider = uislider(app.GridLayout13);
            app.RangeNotchSlider.Limits = [0 80000];
            app.RangeNotchSlider.MajorTicks = [];
            app.RangeNotchSlider.MajorTickLabels = {''};
            app.RangeNotchSlider.ValueChangingFcn = createCallbackFcn(app, @RangeNotchSliderValueChanging, true);
            app.RangeNotchSlider.MinorTicks = [];
            app.DopplerNotchSlider = uislider(app.GridLayout13);
            app.DopplerNotchSlider.Limits = [0 5];
            app.DopplerNotchSlider.MajorTicks = [];
            app.DopplerNotchSlider.MajorTickLabels = {''};
            app.DopplerNotchSlider.MinorTicks = [];
            app.DopplerNotchFilterCheckBox = uicheckbox(app.GridLayout13);
            app.DopplerNotchFilterCheckBox.Text = 'Doppler Notch Filter';
            app.DynamicRangePanel = uipanel(app.GridLayout12);
            app.DynamicRangePanel.Title = 'Dynamic Range';
            app.GridLayout16 = uigridlayout(app.DynamicRangePanel);
            app.MinKnobLabel = uilabel(app.GridLayout16);
            app.MinKnobLabel.Text = 'Min';
            app.DRMinKnob = uiknob(app.GridLayout16, 'continuous');
            app.DRMinKnob.Limits = [0 50];
            app.DRMinKnob.MajorTickLabels = {''};
            app.DRMinKnob.ValueChangedFcn = createCallbackFcn(app, @DRMinKnobValueChanged, true);
            app.DRMinKnob.Value = 40;
            app.MaxKnobLabel = uilabel(app.GridLayout16);
            app.MaxKnobLabel.Text = 'Max';
            app.DRMaxKnob = uiknob(app.GridLayout16, 'continuous');
            app.DRMaxKnob.Limits = [51 120];
            app.DRMaxKnob.MajorTickLabels = {''};
            app.DRMaxKnob.ValueChangedFcn = createCallbackFcn(app, @DRMaxKnobValueChanged, true);
            app.DRMaxKnob.Value = 70;
            app.DRAutoCheckBox = uicheckbox(app.GridLayout16);
            app.DRAutoCheckBox.Text = 'Auto';
            app.DRAutoCheckBox.Value = true;
            app.WaterfallParametersPanel = uipanel(app.GridLayout12);
            app.WaterfallParametersPanel.Title = 'Waterfall Parameters';
            app.GridLayout17 = uigridlayout(app.WaterfallParametersPanel);
            app.HistorysizeframeSpinnerLabel = uilabel(app.GridLayout17);
            app.HistorysizeframeSpinnerLabel.Text = 'History size (frame)';
            app.HistorysizeframeSpinner = uispinner(app.GridLayout17);
            app.HistorysizeframeSpinner.Limits = [1 Inf];
            app.HistorysizeframeSpinner.ValueChangedFcn = createCallbackFcn(app, @HistorysizeframeSpinnerValueChanged, true);
            app.HistorysizeframeSpinner.Value = 50;
            app.Filtering2Panel = uipanel(app.GridLayout12);
            app.Filtering2Panel.Title = 'Filtering 2';
            app.GridLayout21 = uigridlayout(app.Filtering2Panel);
            app.HighPassFilterCheckBox = uicheckbox(app.GridLayout21);
            app.HighPassFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @HighPassFilterCheckBoxValueChanged, true);
            app.HighPassFilterCheckBox.Text = 'High Pass Filter';
            app.PCACheckBox = uicheckbox(app.GridLayout21);
            app.PCACheckBox.ValueChangedFcn = createCallbackFcn(app, @PCACheckBoxValueChanged, true);
            app.PCACheckBox.Text = 'PCA';
            app.PrincipalComponentsSpinnerLabel = uilabel(app.GridLayout21);
            app.PrincipalComponentsSpinnerLabel.Text = '# Principal Components';
            app.PrincipalComponentsSpinner = uispinner(app.GridLayout21);
            app.PrincipalComponentsSpinner.Limits = [1 Inf];
            app.PrincipalComponentsSpinner.ValueChangedFcn = createCallbackFcn(app, @PrincipalComponentsSpinnerValueChanged, true);
            app.PrincipalComponentsSpinner.Enable = 'off';
            app.PrincipalComponentsSpinner.Value = 1;
            app.SweepstoProcessSpinnerLabel = uilabel(app.GridLayout21);
            app.SweepstoProcessSpinnerLabel.Text = '# Sweeps to Process';
            app.SweepstoProcessSpinner = uispinner(app.GridLayout21);
            app.SweepstoProcessSpinner.Step = 500;
            app.SweepstoProcessSpinner.Limits = [500 30000];
            app.SweepstoProcessSpinner.ValueChangedFcn = createCallbackFcn(app, @SweepstoProcessSpinnerValueChanged, true);
            app.SweepstoProcessSpinner.Value = 1000;
            app.SaveSISOStreamPanel = uipanel(app.GridLayout12);
            app.SaveSISOStreamPanel.Title = 'Save SISO Stream';
            app.SaveRealtimestreamCheckBox = uicheckbox(app.SaveSISOStreamPanel);
            app.SaveRealtimestreamCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveRealtimestreamCheckBoxValueChanged, true);
            app.SaveRealtimestreamCheckBox.Enable = 'off';
            app.SaveRealtimestreamCheckBox.Text = 'Save Real-time stream';
            app.RecordSISOSIMOStreamPanel = uipanel(app.GridLayout12);
            app.RecordSISOSIMOStreamPanel.Title = 'Record SISO/SIMO Stream';
            app.SecondsSpinnerLabel_2 = uilabel(app.RecordSISOSIMOStreamPanel);
            app.SecondsSpinnerLabel_2.Text = 'Seconds';
            app.SecondsSpinner = uispinner(app.RecordSISOSIMOStreamPanel);
            app.SecondsSpinner.Limits = [1 Inf];
            app.SecondsSpinner.Value = 5;
            app.StartButton = uibutton(app.RecordSISOSIMOStreamPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.Label = uilabel(app.RecordSISOSIMOStreamPanel);
            app.Label.Text = '';
            app.RecordLamp = uilamp(app.RecordSISOSIMOStreamPanel);
            app.FileMenu = uimenu(app.AncortekSDRadarUIFigure);
            app.FileMenu.Text = 'File';
            app.SavecurrentconfigMenu = uimenu(app.FileMenu);
            app.SavecurrentconfigMenu.MenuSelectedFcn = createCallbackFcn(app, @SavecurrentconfigMenuSelected, true);
            app.SavecurrentconfigMenu.Text = 'Save current config.';
            app.LoadapreviousconfigMenu = uimenu(app.FileMenu);
            app.LoadapreviousconfigMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadapreviousconfigMenuSelected, true);
            app.LoadapreviousconfigMenu.Text = 'Load a previous config.';
            app.AboutMenu = uimenu(app.AncortekSDRadarUIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            % Show the figure after all components are created
            app.AncortekSDRadarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SDRadarApp_MCG_RealTime_Spectro_Demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AncortekSDRadarUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AncortekSDRadarUIFigure)
        end
    end
end